classdef Advanced_Settings_PDR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SystemMonitorTab              matlab.ui.container.Tab
        mx128mLabel                   matlab.ui.control.Label
        ServingCourtSizeLabel         matlab.ui.control.Label
        mx2377mLabel_2                matlab.ui.control.Label
        DoublesCourtSizeLabel         matlab.ui.control.Label
        mx2377mLabel                  matlab.ui.control.Label
        SinglesCourtSizeLabel         matlab.ui.control.Label
        WhiteLabel                    matlab.ui.control.Label
        SidelineColorLabel            matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Team2courtcolorsLabel         matlab.ui.control.Label
        RightServiceCourtLabel_2      matlab.ui.control.Label
        OrangeLabel_8                 matlab.ui.control.Label
        DoublesAlleyLabel_2           matlab.ui.control.Label
        LeftServiceCourtLabel_2       matlab.ui.control.Label
        NoMansLandLabel_2             matlab.ui.control.Label
        OrangeLabel_7                 matlab.ui.control.Label
        OrangeLabel_6                 matlab.ui.control.Label
        OrangeLabel_5                 matlab.ui.control.Label
        OrangeLabel_4                 matlab.ui.control.Label
        NoMansLandLabel               matlab.ui.control.Label
        OrangeLabel_3                 matlab.ui.control.Label
        DoublesAlleyLabel             matlab.ui.control.Label
        OrangeLabel_2                 matlab.ui.control.Label
        LeftServiceCourtLabel         matlab.ui.control.Label
        OrangeLabel                   matlab.ui.control.Label
        RightServiceCourtLabel        matlab.ui.control.Label
        Team1courtcolorsLabel         matlab.ui.control.Label
        Corner4411511885Label         matlab.ui.control.Label
        Corner3411511885Label         matlab.ui.control.Label
        Corner2411511885Label         matlab.ui.control.Label
        Corner1411511885Label         matlab.ui.control.Label
        CornerpositionsXYmetersLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        X14566mY312mZ2009mLabel       matlab.ui.control.Label
        MeasuredCoordsLabel           matlab.ui.control.Label
        X15mY3mZ2mLabel               matlab.ui.control.Label
        ActualCoordsLabel             matlab.ui.control.Label
        CourtSpecsCameraCalibrationValuesLabel  matlab.ui.control.Label
        BallCoordinatesLabel          matlab.ui.control.Label
        CameraViewLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        DiagnosticsTab                matlab.ui.container.Tab
        ErrorCount0Label              matlab.ui.control.Label
        PassFailLabel                 matlab.ui.control.Label
        TestStatusLabel               matlab.ui.control.Label
        TestBallDetectionButton       matlab.ui.control.Button
        FindCircleSensitivitySlider   matlab.ui.control.Slider
        FindCircleSensitivitySliderLabel  matlab.ui.control.Label
        MaskPropertiesLabel           matlab.ui.control.Label
        BlueMinSlider                 matlab.ui.control.Slider
        BlueMinSliderLabel            matlab.ui.control.Label
        BlueMaxSlider                 matlab.ui.control.Slider
        BlueMaxSliderLabel            matlab.ui.control.Label
        GreenMinSlider                matlab.ui.control.Slider
        GreenMinSliderLabel           matlab.ui.control.Label
        GreenMaxSlider                matlab.ui.control.Slider
        GreenMaxSliderLabel           matlab.ui.control.Label
        RedMinSlider                  matlab.ui.control.Slider
        RedMinSliderLabel             matlab.ui.control.Label
        RedMaxSlider                  matlab.ui.control.Slider
        RedMaxSliderLabel             matlab.ui.control.Label
        ManualColorCorrectionLabel    matlab.ui.control.Label
        BallDetectorLabel             matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        ErrorLogTab                   matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        ErrorCountLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;

            LIMG = imread("Camera_1_Sample.png");
            imagesc(app.UIAxes,LIMG);

            RIMG = imread("Camera_2_Sample.png");
            imagesc(app.UIAxes2,RIMG);

            NormLIMG = mat2gray(LIMG);
            GrayLIMG = rgb2gray(NormLIMG);
            edgeLIMG = edge(GrayLIMG);
            [centersLeft, radiiLeft] = imfindcircles(edgeLIMG, [5 480], 'Sensitivity', 0.75);
            imagesc(app.UIAxes_2,edgeLIMG);
            hold(app.UIAxes_2,"on");
            scatter(app.UIAxes_2, centersLeft(1), centersLeft(2), "blue");
            hold(app.UIAxes, "off")
            colormap(app.UIAxes_2, "Gray")
            viscircles(app.UIAxes_2, centersLeft, radiiLeft, 'EdgeColor', 'b');
            viscircles(app.UIAxes, centersLeft, radiiLeft, 'EdgeColor', 'b');

            NormRIMG = mat2gray(RIMG);
            GrayRIMG = rgb2gray(NormRIMG);
            edgeRIMG = edge(GrayRIMG);
            [centersRight, radiiRight] = imfindcircles(edgeRIMG, [5 480], 'Sensitivity', 0.75);
            imagesc(app.UIAxes2_2,edgeRIMG);
            hold(app.UIAxes2_2,"on");
            scatter(app.UIAxes2_2, centersRight(1), centersRight(2), "blue");
            hold(app.UIAxes, "off")
            colormap(app.UIAxes2_2, "Gray")
            viscircles(app.UIAxes2_2, centersRight, radiiRight, 'EdgeColor', 'b');
            viscircles(app.UIAxes2, centersRight, radiiRight, 'EdgeColor', 'b');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.DiagnosticsButton.Enable = 'on';
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemMonitorTab = uitab(app.TabGroup);
            app.SystemMonitorTab.Title = 'System Monitor';
            app.UIAxes2 = uiaxes(app.SystemMonitorTab);
            title(app.UIAxes2, 'Right Camera')
            xlabel(app.UIAxes2, 'u (pixels)')
            ylabel(app.UIAxes2, 'v (pixels)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 751];
            app.UIAxes2.YLim = [0 479];
            app.UIAxes = uiaxes(app.SystemMonitorTab);
            title(app.UIAxes, 'Left Camera')
            xlabel(app.UIAxes, 'u (pixels)')
            ylabel(app.UIAxes, 'v (pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 751];
            app.UIAxes.YLim = [0 479];
            app.CameraViewLabel = uilabel(app.SystemMonitorTab);
            app.CameraViewLabel.Text = 'Camera View';
            app.BallCoordinatesLabel = uilabel(app.SystemMonitorTab);
            app.BallCoordinatesLabel.Text = 'Ball Coordinates';
            app.CourtSpecsCameraCalibrationValuesLabel = uilabel(app.SystemMonitorTab);
            app.CourtSpecsCameraCalibrationValuesLabel.Text = 'Court Specs [Camera Calibration Values]';
            app.ActualCoordsLabel = uilabel(app.SystemMonitorTab);
            app.ActualCoordsLabel.Text = 'Actual Coords:';
            app.X15mY3mZ2mLabel = uilabel(app.SystemMonitorTab);
            app.X15mY3mZ2mLabel.Text = 'X = -15m, Y = 3m, Z = 2m';
            app.MeasuredCoordsLabel = uilabel(app.SystemMonitorTab);
            app.MeasuredCoordsLabel.Text = 'Measured Coords:';
            app.X14566mY312mZ2009mLabel = uilabel(app.SystemMonitorTab);
            app.X14566mY312mZ2009mLabel.Text = 'X = -14.566m, Y = 3.12m, Z = 2.009m';
            app.Label_2 = uilabel(app.SystemMonitorTab);
            app.Label_2.Text = '';
            app.CornerpositionsXYmetersLabel = uilabel(app.SystemMonitorTab);
            app.CornerpositionsXYmetersLabel.Text = 'Corner positions [X, Y] (meters) -';
            app.Corner1411511885Label = uilabel(app.SystemMonitorTab);
            app.Corner1411511885Label.Text = 'Corner #1: [4.115,11.885]';
            app.Corner2411511885Label = uilabel(app.SystemMonitorTab);
            app.Corner2411511885Label.Text = 'Corner #2: [-4.115,11.885]';
            app.Corner3411511885Label = uilabel(app.SystemMonitorTab);
            app.Corner3411511885Label.Text = 'Corner #3: [4.115,-11.885]';
            app.Corner4411511885Label = uilabel(app.SystemMonitorTab);
            app.Corner4411511885Label.Text = 'Corner #4: [-4.115,-11.885]';
            app.Team1courtcolorsLabel = uilabel(app.SystemMonitorTab);
            app.Team1courtcolorsLabel.Text = 'Team #1 court colors:';
            app.RightServiceCourtLabel = uilabel(app.SystemMonitorTab);
            app.RightServiceCourtLabel.Text = 'Right Service Court:';
            app.OrangeLabel = uilabel(app.SystemMonitorTab);
            app.OrangeLabel.Text = 'Orange';
            app.LeftServiceCourtLabel = uilabel(app.SystemMonitorTab);
            app.LeftServiceCourtLabel.Text = 'Left Service Court:';
            app.OrangeLabel_2 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_2.Text = 'Orange';
            app.DoublesAlleyLabel = uilabel(app.SystemMonitorTab);
            app.DoublesAlleyLabel.Text = 'Doubles Alley:';
            app.OrangeLabel_3 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_3.Text = 'Orange';
            app.NoMansLandLabel = uilabel(app.SystemMonitorTab);
            app.NoMansLandLabel.Text = 'No Man''s Land:';
            app.OrangeLabel_4 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_4.Text = 'Orange';
            app.OrangeLabel_5 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_5.Text = 'Orange';
            app.OrangeLabel_6 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_6.Text = 'Orange';
            app.OrangeLabel_7 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_7.Text = 'Orange';
            app.NoMansLandLabel_2 = uilabel(app.SystemMonitorTab);
            app.NoMansLandLabel_2.Text = 'No Man''s Land:';
            app.LeftServiceCourtLabel_2 = uilabel(app.SystemMonitorTab);
            app.LeftServiceCourtLabel_2.Text = 'Left Service Court:';
            app.DoublesAlleyLabel_2 = uilabel(app.SystemMonitorTab);
            app.DoublesAlleyLabel_2.Text = 'Doubles Alley:';
            app.OrangeLabel_8 = uilabel(app.SystemMonitorTab);
            app.OrangeLabel_8.Text = 'Orange';
            app.RightServiceCourtLabel_2 = uilabel(app.SystemMonitorTab);
            app.RightServiceCourtLabel_2.Text = 'Right Service Court:';
            app.Team2courtcolorsLabel = uilabel(app.SystemMonitorTab);
            app.Team2courtcolorsLabel.Text = 'Team #2 court colors:';
            app.Label_3 = uilabel(app.SystemMonitorTab);
            app.Label_3.Text = '';
            app.SidelineColorLabel = uilabel(app.SystemMonitorTab);
            app.SidelineColorLabel.Text = 'Sideline Color:';
            app.WhiteLabel = uilabel(app.SystemMonitorTab);
            app.WhiteLabel.Text = 'White';
            app.SinglesCourtSizeLabel = uilabel(app.SystemMonitorTab);
            app.SinglesCourtSizeLabel.Text = 'Singles Court Size:';
            app.mx2377mLabel = uilabel(app.SystemMonitorTab);
            app.mx2377mLabel.Text = '8.23m x 23.77m';
            app.DoublesCourtSizeLabel = uilabel(app.SystemMonitorTab);
            app.DoublesCourtSizeLabel.Text = 'Doubles Court Size:';
            app.mx2377mLabel_2 = uilabel(app.SystemMonitorTab);
            app.mx2377mLabel_2.Text = '10.97m x 23.77m';
            app.ServingCourtSizeLabel = uilabel(app.SystemMonitorTab);
            app.ServingCourtSizeLabel.Text = 'Serving Court Size:';
            app.mx128mLabel = uilabel(app.SystemMonitorTab);
            app.mx128mLabel.Text = '8.23m x 12.8m';
            app.DiagnosticsTab = uitab(app.TabGroup);
            app.DiagnosticsTab.Title = 'Diagnostics';
            app.UIAxes2_2 = uiaxes(app.DiagnosticsTab);
            title(app.UIAxes2_2, 'Right Mask')
            xlabel(app.UIAxes2_2, 'u (pixels)')
            ylabel(app.UIAxes2_2, 'v (pixels)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 751];
            app.UIAxes2_2.YLim = [0 479];
            app.UIAxes_2 = uiaxes(app.DiagnosticsTab);
            title(app.UIAxes_2, 'Left Mask')
            xlabel(app.UIAxes_2, 'u (pixels)')
            ylabel(app.UIAxes_2, 'v (pixels)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 751];
            app.UIAxes_2.YLim = [0 479];
            app.BallDetectorLabel = uilabel(app.DiagnosticsTab);
            app.BallDetectorLabel.Text = 'Ball Detector';
            app.ManualColorCorrectionLabel = uilabel(app.DiagnosticsTab);
            app.ManualColorCorrectionLabel.Text = 'Manual Color Correction';
            app.RedMaxSliderLabel = uilabel(app.DiagnosticsTab);
            app.RedMaxSliderLabel.Text = 'Red Max';
            app.RedMaxSlider = uislider(app.DiagnosticsTab);
            app.RedMaxSlider.Limits = [0 255];
            app.RedMinSliderLabel = uilabel(app.DiagnosticsTab);
            app.RedMinSliderLabel.Text = 'Red Min';
            app.RedMinSlider = uislider(app.DiagnosticsTab);
            app.RedMinSlider.Limits = [0 255];
            app.GreenMaxSliderLabel = uilabel(app.DiagnosticsTab);
            app.GreenMaxSliderLabel.Text = 'Green Max';
            app.GreenMaxSlider = uislider(app.DiagnosticsTab);
            app.GreenMaxSlider.Limits = [0 255];
            app.GreenMinSliderLabel = uilabel(app.DiagnosticsTab);
            app.GreenMinSliderLabel.Text = 'Green Min';
            app.GreenMinSlider = uislider(app.DiagnosticsTab);
            app.GreenMinSlider.Limits = [0 255];
            app.BlueMaxSliderLabel = uilabel(app.DiagnosticsTab);
            app.BlueMaxSliderLabel.Text = 'Blue Max';
            app.BlueMaxSlider = uislider(app.DiagnosticsTab);
            app.BlueMaxSlider.Limits = [0 255];
            app.BlueMinSliderLabel = uilabel(app.DiagnosticsTab);
            app.BlueMinSliderLabel.Text = 'Blue Min';
            app.BlueMinSlider = uislider(app.DiagnosticsTab);
            app.BlueMinSlider.Limits = [0 255];
            app.MaskPropertiesLabel = uilabel(app.DiagnosticsTab);
            app.MaskPropertiesLabel.Text = 'Mask Properties';
            app.FindCircleSensitivitySliderLabel = uilabel(app.DiagnosticsTab);
            app.FindCircleSensitivitySliderLabel.Text = 'Find Circle Sensitivity';
            app.FindCircleSensitivitySlider = uislider(app.DiagnosticsTab);
            app.FindCircleSensitivitySlider.Value = 90;
            app.TestBallDetectionButton = uibutton(app.DiagnosticsTab, 'push');
            app.TestBallDetectionButton.Text = 'Test Ball Detection';
            app.TestStatusLabel = uilabel(app.DiagnosticsTab);
            app.TestStatusLabel.Text = 'Test Status:';
            app.PassFailLabel = uilabel(app.DiagnosticsTab);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.ErrorCount0Label = uilabel(app.DiagnosticsTab);
            app.ErrorCount0Label.Text = 'Error Count: 0';
            app.ErrorLogTab = uitab(app.TabGroup);
            app.ErrorLogTab.Title = 'Error Log';
            app.ErrorCountLabel = uilabel(app.ErrorLogTab);
            app.ErrorCountLabel.Text = 'Error Count:';
            app.Label = uilabel(app.ErrorLogTab);
            app.Label.Text = '2';
            app.TextArea = uitextarea(app.ErrorLogTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Error (0x035): Frame 765 was not recieved'; 'Error (0x404): Snickerdoodle failed to connect to 127.0.2.1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Advanced_Settings_PDR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end