classdef GUI_PDR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LiveGameplayTab                matlab.ui.container.Tab
        INLabel_2                      matlab.ui.control.Label
        INLabel                        matlab.ui.control.Label
        LiveGameplaypoweredbyHAWCIIMkALabel  matlab.ui.control.Label
        P2_SCRLabel                    matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        P1_SCRLabel                    matlab.ui.control.Label
        GAMELabel                      matlab.ui.control.Label
        TotalVolleyDropDown            matlab.ui.control.DropDown
        TotalVolleyDropDownLabel       matlab.ui.control.Label
        ReplayStatusButton             matlab.ui.control.StateButton
        PlayGameButton                 matlab.ui.control.Button
        TotalServeDropDown             matlab.ui.control.DropDown
        TotalServeDropDownLabel        matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        MatchDetailsTab                matlab.ui.container.Tab
        SCR_LLabel_14                  matlab.ui.control.Label
        serveLabel_2                   matlab.ui.control.Label
        SCR_LLabel_13                  matlab.ui.control.Label
        serveLabel                     matlab.ui.control.Label
        SCR_LLabel_12                  matlab.ui.control.Label
        volleyLabel_2                  matlab.ui.control.Label
        SCR_LLabel_11                  matlab.ui.control.Label
        volleyLabel                    matlab.ui.control.Label
        SCR_LLabel_8                   matlab.ui.control.Label
        SCR_LLabel_7                   matlab.ui.control.Label
        SCR_LLabel_6                   matlab.ui.control.Label
        SCR_LLabel_5                   matlab.ui.control.Label
        bounceLabel_2                  matlab.ui.control.Label
        netLabel_2                     matlab.ui.control.Label
        outLabel_2                     matlab.ui.control.Label
        InLabel_2                      matlab.ui.control.Label
        Player2Label                   matlab.ui.control.Label
        SCR_LLabel_4                   matlab.ui.control.Label
        SCR_LLabel_3                   matlab.ui.control.Label
        SCR_LLabel_2                   matlab.ui.control.Label
        SCR_LLabel                     matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        bounceLabel                    matlab.ui.control.Label
        netLabel                       matlab.ui.control.Label
        outLabel                       matlab.ui.control.Label
        InLabel                        matlab.ui.control.Label
        Player1Label                   matlab.ui.control.Label
        BreakdownLabel                 matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        P2_SCRLabel_2                  matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        P1_SCRLabel_2                  matlab.ui.control.Label
        PropertiesTab                  matlab.ui.container.Tab
        PortNumberEditField_2          matlab.ui.control.EditField
        PortNumberEditField_2Label     matlab.ui.control.Label
        IpAddressEditField_2           matlab.ui.control.EditField
        IpAddressEditField_2Label      matlab.ui.control.Label
        BlenderPropertiesLabel         matlab.ui.control.Label
        BaselinemmEditField            matlab.ui.control.NumericEditField
        BaselinemmEditFieldLabel       matlab.ui.control.Label
        PixelSizemmEditField           matlab.ui.control.NumericEditField
        PixelSizemmEditFieldLabel      matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        ResolutionEditField            matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        ScreenPropertiesLabel          matlab.ui.control.Label
        YawEditField_2                 matlab.ui.control.NumericEditField
        YawEditField_2Label            matlab.ui.control.Label
        RollEditField_2                matlab.ui.control.NumericEditField
        RollEditField_2Label           matlab.ui.control.Label
        PitchEditField_2               matlab.ui.control.NumericEditField
        PitchEditField_2Label          matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        RollEditField                  matlab.ui.control.NumericEditField
        RollEditFieldLabel             matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        CalibrateButton                matlab.ui.control.Button
        CALBR_LABELLabel               matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        CalibrationStatusLabel         matlab.ui.control.Label
        FocalLengthmmEditField         matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel    matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        SVLABELButton                  matlab.ui.control.StateButton
        ZAxismEditField_2              matlab.ui.control.NumericEditField
        ZAxismEditField_2Label         matlab.ui.control.Label
        YAxismEditField_2              matlab.ui.control.NumericEditField
        YAxismEditField_2Label         matlab.ui.control.Label
        Camera2Label                   matlab.ui.control.Label
        ZAxismEditField                matlab.ui.control.NumericEditField
        ZAxismEditFieldLabel           matlab.ui.control.Label
        YAxismEditField                matlab.ui.control.NumericEditField
        YAxismEditFieldLabel           matlab.ui.control.Label
        Camera1Label                   matlab.ui.control.Label
        PortNumberEditField            matlab.ui.control.EditField
        PortNumberEditFieldLabel       matlab.ui.control.Label
        IpAddressEditField             matlab.ui.control.EditField
        IpAddressEditFieldLabel        matlab.ui.control.Label
        CameraPropertiesLabel          matlab.ui.control.Label
        ServerPropertiesLabel          matlab.ui.control.Label
        DebugTab                       matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        FrameMonitorTab                matlab.ui.container.Tab
        ServerConnectionLabel          matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        DetectBallButton               matlab.ui.control.Button
        MaskViewLabel                  matlab.ui.control.Label
        CameraViewLabel                matlab.ui.control.Label
        RecieveImageButton             matlab.ui.control.Button
        ZAnsLabel                      matlab.ui.control.Label
        ZaxisLabel                     matlab.ui.control.Label
        YAnsLabel                      matlab.ui.control.Label
        YaxisLabel                     matlab.ui.control.Label
        XAnsLabel                      matlab.ui.control.Label
        XaxisLabel                     matlab.ui.control.Label
        BallCoordinatesLabel           matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        EstablishButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        MaskPropertiesTab              matlab.ui.container.Tab
        BallColorDropDown              matlab.ui.control.DropDown
        BallColorDropDownLabel         matlab.ui.control.Label
        ColorPresetsLabel              matlab.ui.control.Label
        CourtColorDropDown             matlab.ui.control.DropDown
        CourtColorDropDownLabel        matlab.ui.control.Label
        BlueMinSlider                  matlab.ui.control.Slider
        BlueMinSliderLabel             matlab.ui.control.Label
        BlueMaxSlider                  matlab.ui.control.Slider
        BlueMaxSliderLabel             matlab.ui.control.Label
        GreenMinSlider                 matlab.ui.control.Slider
        GreenMinSliderLabel            matlab.ui.control.Label
        GreenMaxSlider                 matlab.ui.control.Slider
        GreenMaxSliderLabel            matlab.ui.control.Label
        RedMinSlider                   matlab.ui.control.Slider
        RedMinSliderLabel              matlab.ui.control.Label
        RedMaxSlider                   matlab.ui.control.Slider
        RedMaxSliderLabel              matlab.ui.control.Label
        ManualColorCorrectionLabel     matlab.ui.control.Label
        MaskViewLabel_2                matlab.ui.control.Label
        FindCircleSensitivitySlider_2  matlab.ui.control.Slider
        FindCircleSensitivitySlider_2Label  matlab.ui.control.Label
        MaskPropertiesLabel_2          matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
        AccuracyTab                    matlab.ui.container.Tab
        SystemAccuracyCameraPositionLabel  matlab.ui.control.Label
        SystemAccuracyBallPositionLabel  matlab.ui.control.Label
        UIAxes5_2                      matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        RestitutionTab                 matlab.ui.container.Tab
        AnalyzeButton                  matlab.ui.control.Button
        MAT_LLabel_2                   matlab.ui.control.Label
        ActualCourtSurfaceLabel        matlab.ui.control.Label
        MAT_LLabel                     matlab.ui.control.Label
        AssumedCourtSurfaceLabel       matlab.ui.control.Label
        CoefficientofRestitutionLabel  matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        AboutTab                       matlab.ui.container.Tab
        OranBetzLabel                  matlab.ui.control.Label
        StephenBianchiLabel            matlab.ui.control.Label
        DavidWanLabel                  matlab.ui.control.Label
        MatthewCellsLabel              matlab.ui.control.Label
        AvaTeegardenLabel              matlab.ui.control.Label
        SantiagoSanchezLabel           matlab.ui.control.Label
        ShamsBelalLabel                matlab.ui.control.Label
        TeamChallengersLabel           matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Version02Label                 matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ChangelogTab                   matlab.ui.container.Tab
        AddedIndicatorsforballInOutStatusnexttoLiveTVframeLabel  matlab.ui.control.Label
        NewcoefficientrestitutionGUILabel  matlab.ui.control.Label
        ManualPresetcolorcorrectionaddedLabel  matlab.ui.control.Label
        AboutnowhasadescriptionofproductandteammembersLabel  matlab.ui.control.Label
        AddedBlenderPropertiesinPropertiesTabLabel  matlab.ui.control.Label
        HomeisnowAboutandpushedtotheendofthetablistLabel  matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        Version02Label_2               matlab.ui.control.Label
        BugsFixesLabel                 matlab.ui.control.Label
        ChangesLabel                   matlab.ui.control.Label
        HAWCIIMkALabel                 matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
    end


    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.INLabel.BackgroundColor = 'none'; app.INLabel.Text = '';
            app.INLabel_2.BackgroundColor = 'none'; app.INLabel_2.Text = '';

            app.ReplayStatusButton.Text = 'Not Available';
            app.SVLABELButton.Text = 'Save';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveGameplayTab = uitab(app.TabGroup);
            app.LiveGameplayTab.Title = 'Live Gameplay';
            app.UIAxes4 = uiaxes(app.LiveGameplayTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.TotalServeDropDownLabel = uilabel(app.LiveGameplayTab);
            app.TotalServeDropDownLabel.Text = 'Total Serve';
            app.TotalServeDropDown = uidropdown(app.LiveGameplayTab);
            app.TotalServeDropDown.Items = {'1', '2', '3', '4', '5'};
            app.TotalServeDropDown.Value = '1';
            app.PlayGameButton = uibutton(app.LiveGameplayTab, 'push');
            app.PlayGameButton.Text = 'Play Game';
            app.ReplayStatusButton = uibutton(app.LiveGameplayTab, 'state');
            app.ReplayStatusButton.Text = 'Replay Status';
            app.ReplayStatusButton.Value = true;
            app.TotalVolleyDropDownLabel = uilabel(app.LiveGameplayTab);
            app.TotalVolleyDropDownLabel.Text = 'Total Volley';
            app.TotalVolleyDropDown = uidropdown(app.LiveGameplayTab);
            app.TotalVolleyDropDown.Items = {'1', '2', '3', '4', '5'};
            app.TotalVolleyDropDown.Value = '1';
            app.GAMELabel = uilabel(app.LiveGameplayTab);
            app.GAMELabel.Text = 'GAME:';
            app.P1_SCRLabel = uilabel(app.LiveGameplayTab);
            app.P1_SCRLabel.Text = 'P1_SCR';
            app.Label_7 = uilabel(app.LiveGameplayTab);
            app.Label_7.Text = '--';
            app.P2_SCRLabel = uilabel(app.LiveGameplayTab);
            app.P2_SCRLabel.Text = 'P2_SCR';
            app.LiveGameplaypoweredbyHAWCIIMkALabel = uilabel(app.LiveGameplayTab);
            app.LiveGameplaypoweredbyHAWCIIMkALabel.Text = 'Live Gameplay powered by HAWC-II Mk A';
            app.INLabel = uilabel(app.LiveGameplayTab);
            app.INLabel.Text = 'IN';
            app.INLabel_2 = uilabel(app.LiveGameplayTab);
            app.INLabel_2.Text = 'IN';
            app.MatchDetailsTab = uitab(app.TabGroup);
            app.MatchDetailsTab.Title = 'Match Details';
            app.P1_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.P1_SCRLabel_2.Text = 'P1_SCR';
            app.Label_8 = uilabel(app.MatchDetailsTab);
            app.Label_8.Text = '--';
            app.P2_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.P2_SCRLabel_2.Text = 'P2_SCR';
            app.Label_9 = uilabel(app.MatchDetailsTab);
            app.BreakdownLabel = uilabel(app.MatchDetailsTab);
            app.BreakdownLabel.Text = 'Breakdown';
            app.Player1Label = uilabel(app.MatchDetailsTab);
            app.Player1Label.Text = 'Player #1:';
            app.InLabel = uilabel(app.MatchDetailsTab);
            app.InLabel.Text = 'In -';
            app.outLabel = uilabel(app.MatchDetailsTab);
            app.outLabel.Text = 'out -';
            app.netLabel = uilabel(app.MatchDetailsTab);
            app.netLabel.Text = 'net -';
            app.bounceLabel = uilabel(app.MatchDetailsTab);
            app.bounceLabel.Text = 'bounce -';
            app.Label_10 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel.Text = 'SCR_L';
            app.SCR_LLabel_2 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_2.Text = 'SCR_L';
            app.SCR_LLabel_3 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_3.Text = 'SCR_L';
            app.SCR_LLabel_4 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_4.Text = 'SCR_L';
            app.Player2Label = uilabel(app.MatchDetailsTab);
            app.Player2Label.Text = 'Player #2:';
            app.InLabel_2 = uilabel(app.MatchDetailsTab);
            app.InLabel_2.Text = 'In -';
            app.outLabel_2 = uilabel(app.MatchDetailsTab);
            app.outLabel_2.Text = 'out -';
            app.netLabel_2 = uilabel(app.MatchDetailsTab);
            app.netLabel_2.Text = 'net -';
            app.bounceLabel_2 = uilabel(app.MatchDetailsTab);
            app.bounceLabel_2.Text = 'bounce -';
            app.SCR_LLabel_5 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_5.Text = 'SCR_L';
            app.SCR_LLabel_6 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_6.Text = 'SCR_L';
            app.SCR_LLabel_7 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_7.Text = 'SCR_L';
            app.SCR_LLabel_8 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_8.Text = 'SCR_L';
            app.volleyLabel = uilabel(app.MatchDetailsTab);
            app.volleyLabel.Text = 'volley -';
            app.SCR_LLabel_11 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_11.Text = 'SCR_L';
            app.volleyLabel_2 = uilabel(app.MatchDetailsTab);
            app.volleyLabel_2.Text = 'volley -';
            app.SCR_LLabel_12 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_12.Text = 'SCR_L';
            app.serveLabel = uilabel(app.MatchDetailsTab);
            app.serveLabel.Text = 'serve -';
            app.SCR_LLabel_13 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_13.Text = 'SCR_L';
            app.serveLabel_2 = uilabel(app.MatchDetailsTab);
            app.serveLabel_2.Text = 'serve -';
            app.SCR_LLabel_14 = uilabel(app.MatchDetailsTab);
            app.SCR_LLabel_14.Text = 'SCR_L';
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.ServerPropertiesLabel = uilabel(app.PropertiesTab);
            app.ServerPropertiesLabel.Text = 'Server Properties';
            app.CameraPropertiesLabel = uilabel(app.PropertiesTab);
            app.CameraPropertiesLabel.Text = 'Camera Properties';
            app.IpAddressEditFieldLabel = uilabel(app.PropertiesTab);
            app.IpAddressEditFieldLabel.Text = 'Ip Address';
            app.IpAddressEditField = uieditfield(app.PropertiesTab, 'text');
            app.PortNumberEditFieldLabel = uilabel(app.PropertiesTab);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.PropertiesTab, 'text');
            app.Camera1Label = uilabel(app.PropertiesTab);
            app.Camera1Label.Text = 'Camera #1';
            app.YAxismEditFieldLabel = uilabel(app.PropertiesTab);
            app.YAxismEditFieldLabel.Text = 'Y Axis [m]';
            app.YAxismEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.ZAxismEditFieldLabel = uilabel(app.PropertiesTab);
            app.ZAxismEditFieldLabel.Text = 'Z Axis [m]';
            app.ZAxismEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.Camera2Label = uilabel(app.PropertiesTab);
            app.Camera2Label.Text = 'Camera #2';
            app.YAxismEditField_2Label = uilabel(app.PropertiesTab);
            app.YAxismEditField_2Label.Text = 'Y Axis [m]';
            app.YAxismEditField_2 = uieditfield(app.PropertiesTab, 'numeric');
            app.ZAxismEditField_2Label = uilabel(app.PropertiesTab);
            app.ZAxismEditField_2Label.Text = 'Z Axis [m]';
            app.ZAxismEditField_2 = uieditfield(app.PropertiesTab, 'numeric');
            app.SVLABELButton = uibutton(app.PropertiesTab, 'state');
            app.SVLABELButton.Text = 'SV LABEL';
            app.Label_4 = uilabel(app.PropertiesTab);
            app.FocalLengthmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length [mm]';
            app.FocalLengthmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.CalibrationStatusLabel = uilabel(app.PropertiesTab);
            app.CalibrationStatusLabel.Text = 'Calibration Status:';
            app.Label_5 = uilabel(app.PropertiesTab);
            app.CALBR_LABELLabel = uilabel(app.PropertiesTab);
            app.CALBR_LABELLabel.Text = 'CALBR_LABEL';
            app.CalibrateButton = uibutton(app.PropertiesTab, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.PitchEditFieldLabel = uilabel(app.PropertiesTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.RollEditFieldLabel = uilabel(app.PropertiesTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.PropertiesTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.PitchEditField_2Label = uilabel(app.PropertiesTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.PropertiesTab, 'numeric');
            app.RollEditField_2Label = uilabel(app.PropertiesTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.PropertiesTab, 'numeric');
            app.YawEditField_2Label = uilabel(app.PropertiesTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.PropertiesTab, 'numeric');
            app.ScreenPropertiesLabel = uilabel(app.PropertiesTab);
            app.ScreenPropertiesLabel.Text = 'Screen Properties';
            app.ResolutionEditFieldLabel = uilabel(app.PropertiesTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.xLabel = uilabel(app.PropertiesTab);
            app.xLabel.Text = 'x';
            app.EditField = uieditfield(app.PropertiesTab, 'numeric');
            app.PixelSizemmEditFieldLabel = uilabel(app.PropertiesTab);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size [mm]';
            app.PixelSizemmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.BaselinemmEditFieldLabel = uilabel(app.PropertiesTab);
            app.BaselinemmEditFieldLabel.Text = 'Baseline [mm]';
            app.BaselinemmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.BlenderPropertiesLabel = uilabel(app.PropertiesTab);
            app.BlenderPropertiesLabel.Text = 'Blender Properties';
            app.IpAddressEditField_2Label = uilabel(app.PropertiesTab);
            app.IpAddressEditField_2Label.Text = 'Ip Address';
            app.IpAddressEditField_2 = uieditfield(app.PropertiesTab, 'text');
            app.PortNumberEditField_2Label = uilabel(app.PropertiesTab);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditField_2 = uieditfield(app.PropertiesTab, 'text');
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.TabGroup2 = uitabgroup(app.DebugTab);
            app.FrameMonitorTab = uitab(app.TabGroup2);
            app.FrameMonitorTab.Title = 'Frame Monitor';
            app.UIAxes2_2 = uiaxes(app.FrameMonitorTab);
            title(app.UIAxes2_2, 'Right Mask')
            xlabel(app.UIAxes2_2, 'u (pixels)')
            ylabel(app.UIAxes2_2, 'v (pixels)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 751];
            app.UIAxes2_2.YLim = [0 479];
            app.UIAxes_2 = uiaxes(app.FrameMonitorTab);
            title(app.UIAxes_2, 'Left Mask')
            xlabel(app.UIAxes_2, 'u (pixels)')
            ylabel(app.UIAxes_2, 'v (pixels)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 751];
            app.UIAxes_2.YLim = [0 479];
            app.UIAxes2 = uiaxes(app.FrameMonitorTab);
            title(app.UIAxes2, 'Right Camera')
            xlabel(app.UIAxes2, 'u (pixels)')
            ylabel(app.UIAxes2, 'v (pixels)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 751];
            app.UIAxes2.YLim = [0 479];
            app.UIAxes = uiaxes(app.FrameMonitorTab);
            title(app.UIAxes, 'Left Camera')
            xlabel(app.UIAxes, 'u (pixels)')
            ylabel(app.UIAxes, 'v (pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 751];
            app.UIAxes.YLim = [0 479];
            app.EstablishButton = uibutton(app.FrameMonitorTab, 'push');
            app.EstablishButton.Text = 'Establish';
            app.Label_2 = uilabel(app.FrameMonitorTab);
            app.Label_2.Text = '****';
            app.BallCoordinatesLabel = uilabel(app.FrameMonitorTab);
            app.BallCoordinatesLabel.Text = 'Ball Coordinates';
            app.XaxisLabel = uilabel(app.FrameMonitorTab);
            app.XaxisLabel.Text = 'X axis:';
            app.XAnsLabel = uilabel(app.FrameMonitorTab);
            app.XAnsLabel.Text = 'XAns';
            app.YaxisLabel = uilabel(app.FrameMonitorTab);
            app.YaxisLabel.Text = 'Y axis:';
            app.YAnsLabel = uilabel(app.FrameMonitorTab);
            app.YAnsLabel.Text = 'YAns';
            app.ZaxisLabel = uilabel(app.FrameMonitorTab);
            app.ZaxisLabel.Text = 'Z axis:';
            app.ZAnsLabel = uilabel(app.FrameMonitorTab);
            app.ZAnsLabel.Text = 'ZAns';
            app.RecieveImageButton = uibutton(app.FrameMonitorTab, 'push');
            app.RecieveImageButton.Text = 'Recieve Image';
            app.CameraViewLabel = uilabel(app.FrameMonitorTab);
            app.CameraViewLabel.Text = 'Camera View';
            app.MaskViewLabel = uilabel(app.FrameMonitorTab);
            app.MaskViewLabel.Text = 'Mask View';
            app.DetectBallButton = uibutton(app.FrameMonitorTab, 'push');
            app.DetectBallButton.Text = 'Detect Ball';
            app.mLabel = uilabel(app.FrameMonitorTab);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.FrameMonitorTab);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.FrameMonitorTab);
            app.mLabel_3.Text = 'm';
            app.ServerConnectionLabel = uilabel(app.FrameMonitorTab);
            app.ServerConnectionLabel.Text = 'Server Connection:';
            app.MaskPropertiesTab = uitab(app.TabGroup2);
            app.MaskPropertiesTab.Title = 'Mask Properties';
            app.UIAxes2_3 = uiaxes(app.MaskPropertiesTab);
            title(app.UIAxes2_3, 'Right Mask')
            xlabel(app.UIAxes2_3, 'u (pixels)')
            ylabel(app.UIAxes2_3, 'v (pixels)')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XLim = [0 751];
            app.UIAxes2_3.YLim = [0 479];
            app.UIAxes_3 = uiaxes(app.MaskPropertiesTab);
            title(app.UIAxes_3, 'Left Mask')
            xlabel(app.UIAxes_3, 'u (pixels)')
            ylabel(app.UIAxes_3, 'v (pixels)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 751];
            app.UIAxes_3.YLim = [0 479];
            app.MaskPropertiesLabel_2 = uilabel(app.MaskPropertiesTab);
            app.MaskPropertiesLabel_2.Text = 'Mask Properties';
            app.FindCircleSensitivitySlider_2Label = uilabel(app.MaskPropertiesTab);
            app.FindCircleSensitivitySlider_2Label.Text = 'Find Circle Sensitivity';
            app.FindCircleSensitivitySlider_2 = uislider(app.MaskPropertiesTab);
            app.FindCircleSensitivitySlider_2.Value = 90;
            app.MaskViewLabel_2 = uilabel(app.MaskPropertiesTab);
            app.MaskViewLabel_2.Text = 'Mask View';
            app.ManualColorCorrectionLabel = uilabel(app.MaskPropertiesTab);
            app.ManualColorCorrectionLabel.Text = 'Manual Color Correction';
            app.RedMaxSliderLabel = uilabel(app.MaskPropertiesTab);
            app.RedMaxSliderLabel.Text = 'Red Max';
            app.RedMaxSlider = uislider(app.MaskPropertiesTab);
            app.RedMaxSlider.Limits = [0 255];
            app.RedMinSliderLabel = uilabel(app.MaskPropertiesTab);
            app.RedMinSliderLabel.Text = 'Red Min';
            app.RedMinSlider = uislider(app.MaskPropertiesTab);
            app.RedMinSlider.Limits = [0 255];
            app.GreenMaxSliderLabel = uilabel(app.MaskPropertiesTab);
            app.GreenMaxSliderLabel.Text = 'Green Max';
            app.GreenMaxSlider = uislider(app.MaskPropertiesTab);
            app.GreenMaxSlider.Limits = [0 255];
            app.GreenMinSliderLabel = uilabel(app.MaskPropertiesTab);
            app.GreenMinSliderLabel.Text = 'Green Min';
            app.GreenMinSlider = uislider(app.MaskPropertiesTab);
            app.GreenMinSlider.Limits = [0 255];
            app.BlueMaxSliderLabel = uilabel(app.MaskPropertiesTab);
            app.BlueMaxSliderLabel.Text = 'Blue Max';
            app.BlueMaxSlider = uislider(app.MaskPropertiesTab);
            app.BlueMaxSlider.Limits = [0 255];
            app.BlueMinSliderLabel = uilabel(app.MaskPropertiesTab);
            app.BlueMinSliderLabel.Text = 'Blue Min';
            app.BlueMinSlider = uislider(app.MaskPropertiesTab);
            app.BlueMinSlider.Limits = [0 255];
            app.CourtColorDropDownLabel = uilabel(app.MaskPropertiesTab);
            app.CourtColorDropDownLabel.Text = 'Court Color';
            app.CourtColorDropDown = uidropdown(app.MaskPropertiesTab);
            app.CourtColorDropDown.Items = {'Green', 'Red', 'Blue', 'Gray', 'Purple'};
            app.CourtColorDropDown.Value = 'Green';
            app.ColorPresetsLabel = uilabel(app.MaskPropertiesTab);
            app.ColorPresetsLabel.Text = 'Color Presets';
            app.BallColorDropDownLabel = uilabel(app.MaskPropertiesTab);
            app.BallColorDropDownLabel.Text = 'Ball Color';
            app.BallColorDropDown = uidropdown(app.MaskPropertiesTab);
            app.BallColorDropDown.Items = {'Red', 'Orange', 'Green', 'White'};
            app.BallColorDropDown.Value = 'White';
            app.AccuracyTab = uitab(app.TabGroup2);
            app.AccuracyTab.Title = 'Accuracy';
            app.UIAxes5 = uiaxes(app.AccuracyTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.AccuracyTab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.SystemAccuracyBallPositionLabel = uilabel(app.AccuracyTab);
            app.SystemAccuracyBallPositionLabel.Text = 'System Accuracy [Ball Position]';
            app.SystemAccuracyCameraPositionLabel = uilabel(app.AccuracyTab);
            app.SystemAccuracyCameraPositionLabel.Text = 'System Accuracy [Camera Position]';
            app.RestitutionTab = uitab(app.TabGroup2);
            app.RestitutionTab.Title = 'Restitution';
            app.UIAxes6 = uiaxes(app.RestitutionTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CoefficientofRestitutionLabel = uilabel(app.RestitutionTab);
            app.CoefficientofRestitutionLabel.Text = 'Coefficient of Restitution';
            app.AssumedCourtSurfaceLabel = uilabel(app.RestitutionTab);
            app.AssumedCourtSurfaceLabel.Text = 'Assumed Court Surface:';
            app.MAT_LLabel = uilabel(app.RestitutionTab);
            app.MAT_LLabel.Text = 'MAT_L';
            app.ActualCourtSurfaceLabel = uilabel(app.RestitutionTab);
            app.ActualCourtSurfaceLabel.Text = 'Actual Court Surface:';
            app.MAT_LLabel_2 = uilabel(app.RestitutionTab);
            app.MAT_LLabel_2.Text = 'MAT_L';
            app.AnalyzeButton = uibutton(app.RestitutionTab, 'push');
            app.AnalyzeButton.Text = 'Analyze';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'hq720.jpg');
            app.Label_3 = uilabel(app.AboutTab);
            app.Label_3.Text = '    Since 2003, Hawk-Eye has been used to make calls for the United States Tennis Association [UTSA]. Recently it has been determined that under windy conditions the calls are not very accurate and creates significant errors. The HAWC-II Mk A eliminates those inaccurate results using a state-of-the-art stereo camera tracker.';
            app.Version02Label = uilabel(app.AboutTab);
            app.Version02Label.Text = 'Version: 0.2';
            app.Label_6 = uilabel(app.AboutTab);
            app.Label_6.Text = '3/23/2025';
            app.Label_11 = uilabel(app.AboutTab);
            app.Label_11.Text = 'Utilizing a FPGA-based controller, Snickerdoodle ___, and two ONSEMI MT9M114 camera sensor. Challengers aim to provide UTSA with a price efficient product that doesn''t impact performance.';
            app.TeamChallengersLabel = uilabel(app.AboutTab);
            app.TeamChallengersLabel.Text = 'Team Challengers';
            app.ShamsBelalLabel = uilabel(app.AboutTab);
            app.ShamsBelalLabel.Text = 'Shams Belal';
            app.SantiagoSanchezLabel = uilabel(app.AboutTab);
            app.SantiagoSanchezLabel.Text = 'Santiago Sanchez';
            app.AvaTeegardenLabel = uilabel(app.AboutTab);
            app.AvaTeegardenLabel.Text = 'Ava Teegarden';
            app.MatthewCellsLabel = uilabel(app.AboutTab);
            app.MatthewCellsLabel.Text = 'Matthew Cells';
            app.DavidWanLabel = uilabel(app.AboutTab);
            app.DavidWanLabel.Text = 'David Wan';
            app.StephenBianchiLabel = uilabel(app.AboutTab);
            app.StephenBianchiLabel.Text = 'Stephen Bianchi';
            app.OranBetzLabel = uilabel(app.AboutTab);
            app.OranBetzLabel.Text = 'Oran Betz';
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.Image2 = uiimage(app.ChangelogTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'hq720.jpg');
            app.HAWCIIMkALabel = uilabel(app.ChangelogTab);
            app.HAWCIIMkALabel.Text = 'HAWC-II Mk A';
            app.ChangesLabel = uilabel(app.ChangelogTab);
            app.ChangesLabel.Text = 'Changes';
            app.BugsFixesLabel = uilabel(app.ChangelogTab);
            app.BugsFixesLabel.Text = 'Bugs & Fixes';
            app.Version02Label_2 = uilabel(app.ChangelogTab);
            app.Version02Label_2.Text = 'Version: 0.2';
            app.Label3 = uilabel(app.ChangelogTab);
            app.Label3.Text = '- Literally nothing works... All of it is just barebones drybones ahh GUI with no function.';
            app.HomeisnowAboutandpushedtotheendofthetablistLabel = uilabel(app.ChangelogTab);
            app.HomeisnowAboutandpushedtotheendofthetablistLabel.Text = '- "Home" is now "About" and pushed to the end of the tab list';
            app.AddedBlenderPropertiesinPropertiesTabLabel = uilabel(app.ChangelogTab);
            app.AddedBlenderPropertiesinPropertiesTabLabel.Text = '- Added Blender Properties in "Properties" Tab';
            app.AboutnowhasadescriptionofproductandteammembersLabel = uilabel(app.ChangelogTab);
            app.AboutnowhasadescriptionofproductandteammembersLabel.Text = '- "About" now has a description of product and team members';
            app.ManualPresetcolorcorrectionaddedLabel = uilabel(app.ChangelogTab);
            app.ManualPresetcolorcorrectionaddedLabel.Text = '- Manual + Preset color correction added';
            app.NewcoefficientrestitutionGUILabel = uilabel(app.ChangelogTab);
            app.NewcoefficientrestitutionGUILabel.Text = '- New coefficient restitution GUI';
            app.AddedIndicatorsforballInOutStatusnexttoLiveTVframeLabel = uilabel(app.ChangelogTab);
            app.AddedIndicatorsforballInOutStatusnexttoLiveTVframeLabel.Text = '- Added Indicators for ball In/Out Status next to Live TV frame';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PDR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end