classdef GUI_PDR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LiveGameplayTab              matlab.ui.container.Tab
        ERR_CNTLabel                 matlab.ui.control.Label
        ErrorCountLabel              matlab.ui.control.Label
        PlayGameButton               matlab.ui.control.StateButton
        INLabel_2                    matlab.ui.control.Label
        INLabel                      matlab.ui.control.Label
        LiveGameplaypoweredbyHAWCIIMkALabel  matlab.ui.control.Label
        P2_SCRLabel_2                matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        P1_SCRLabel_2                matlab.ui.control.Label
        GAMELabel                    matlab.ui.control.Label
        TotalVolleyDropDown          matlab.ui.control.DropDown
        TotalVolleyDropDownLabel     matlab.ui.control.Label
        ReplayStatusButton           matlab.ui.control.StateButton
        TotalServeDropDown           matlab.ui.control.DropDown
        TotalServeDropDownLabel      matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        MatchDetailsTab              matlab.ui.container.Tab
        AnalyzeButton_2              matlab.ui.control.Button
        SRFC_ACCLabel                matlab.ui.control.Label
        ActualCourtSurfaceLabel_2    matlab.ui.control.Label
        SRFC_ASMLabel                matlab.ui.control.Label
        AssumedCourtSurfaceLabel_2   matlab.ui.control.Label
        CoefficientofRestitutionLabel_2  matlab.ui.control.Label
        SystemAccuracyCameraPositionLabel_2  matlab.ui.control.Label
        SystemAccuracyBallPositionLabel_2  matlab.ui.control.Label
        SRV_SCRLabel_2               matlab.ui.control.Label
        serveLabel_2                 matlab.ui.control.Label
        SRV_SCRLabel                 matlab.ui.control.Label
        serveLabel                   matlab.ui.control.Label
        VLY_SCRLabel_2               matlab.ui.control.Label
        volleyLabel_2                matlab.ui.control.Label
        VLY_SCRLabel                 matlab.ui.control.Label
        volleyLabel                  matlab.ui.control.Label
        BNC_SCRLabel_2               matlab.ui.control.Label
        NET_SCRLabel_2               matlab.ui.control.Label
        OUT_SCRLabel_2               matlab.ui.control.Label
        IN_SCRLabel_2                matlab.ui.control.Label
        bounceLabel_2                matlab.ui.control.Label
        netLabel_2                   matlab.ui.control.Label
        outLabel_2                   matlab.ui.control.Label
        InLabel_2                    matlab.ui.control.Label
        Player2Label                 matlab.ui.control.Label
        BNC_SCRLabel                 matlab.ui.control.Label
        NET_SCRLabel                 matlab.ui.control.Label
        OUT_SCRLabel                 matlab.ui.control.Label
        IN_SCRLabel                  matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        bounceLabel                  matlab.ui.control.Label
        netLabel                     matlab.ui.control.Label
        outLabel                     matlab.ui.control.Label
        InLabel                      matlab.ui.control.Label
        Player1Label                 matlab.ui.control.Label
        BreakdownLabel               matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        P2_SCRLabel                  matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        P1_SCRLabel                  matlab.ui.control.Label
        UIAxes6_2                    matlab.ui.control.UIAxes
        UIAxes5_4                    matlab.ui.control.UIAxes
        UIAxes5_3                    matlab.ui.control.UIAxes
        SettingsTab                  matlab.ui.container.Tab
        AdvancedButton               matlab.ui.control.Button
        BallColorDropDown_2          matlab.ui.control.DropDown
        BallColorDropDown_2Label     matlab.ui.control.Label
        CourtColorDropDown_2         matlab.ui.control.DropDown
        CourtColorDropDown_2Label    matlab.ui.control.Label
        GamePropertiesLabel          matlab.ui.control.Label
        PortNumberEditField_2        matlab.ui.control.EditField
        PortNumberEditField_2Label   matlab.ui.control.Label
        IpAddressEditField_2         matlab.ui.control.EditField
        IpAddressEditField_2Label    matlab.ui.control.Label
        BlenderPropertiesLabel       matlab.ui.control.Label
        BaselinemmEditField          matlab.ui.control.NumericEditField
        BaselinemmEditFieldLabel     matlab.ui.control.Label
        PixelSizemmEditField         matlab.ui.control.NumericEditField
        PixelSizemmEditFieldLabel    matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        xLabel                       matlab.ui.control.Label
        ResolutionEditField          matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel     matlab.ui.control.Label
        ScreenPropertiesLabel        matlab.ui.control.Label
        YawEditField_2               matlab.ui.control.NumericEditField
        YawEditField_2Label          matlab.ui.control.Label
        RollEditField_2              matlab.ui.control.NumericEditField
        RollEditField_2Label         matlab.ui.control.Label
        PitchEditField_2             matlab.ui.control.NumericEditField
        PitchEditField_2Label        matlab.ui.control.Label
        YawEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel            matlab.ui.control.Label
        RollEditField                matlab.ui.control.NumericEditField
        RollEditFieldLabel           matlab.ui.control.Label
        PitchEditField               matlab.ui.control.NumericEditField
        PitchEditFieldLabel          matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        CALBR_LABELLabel             matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        CalibrationStatusLabel       matlab.ui.control.Label
        FocalLengthmmEditField       matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel  matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        SVLABELButton                matlab.ui.control.StateButton
        ZAxismEditField_2            matlab.ui.control.NumericEditField
        ZAxismEditField_2Label       matlab.ui.control.Label
        YAxismEditField_2            matlab.ui.control.NumericEditField
        YAxismEditField_2Label       matlab.ui.control.Label
        Camera2Label                 matlab.ui.control.Label
        ZAxismEditField              matlab.ui.control.NumericEditField
        ZAxismEditFieldLabel         matlab.ui.control.Label
        YAxismEditField              matlab.ui.control.NumericEditField
        YAxismEditFieldLabel         matlab.ui.control.Label
        Camera1Label                 matlab.ui.control.Label
        PortNumberEditField          matlab.ui.control.EditField
        PortNumberEditFieldLabel     matlab.ui.control.Label
        IpAddressEditField           matlab.ui.control.EditField
        IpAddressEditFieldLabel      matlab.ui.control.Label
        CameraPropertiesLabel        matlab.ui.control.Label
        ServerPropertiesLabel        matlab.ui.control.Label
        AboutTab                     matlab.ui.container.Tab
        OranBetzLabel                matlab.ui.control.Label
        StephenBianchiLabel          matlab.ui.control.Label
        DavidWanLabel                matlab.ui.control.Label
        MatthewCellsLabel            matlab.ui.control.Label
        AvaTeegardenLabel            matlab.ui.control.Label
        SantiagoSanchezLabel         matlab.ui.control.Label
        ShamsBelalLabel              matlab.ui.control.Label
        TeamChallengersLabel         matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Version03Label_2             matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ChangelogTab                 matlab.ui.container.Tab
        AddedIndicatorsforballInOutStatusnexttoLiveTVframeLabel  matlab.ui.control.Label
        NewcoefficientrestitutionGUILabel  matlab.ui.control.Label
        ManualPresetcolorcorrectionaddedLabel  matlab.ui.control.Label
        AboutnowhasadescriptionofproductandteammembersLabel  matlab.ui.control.Label
        AddedBlenderPropertiesinPropertiesTabLabel  matlab.ui.control.Label
        HomeisnowAboutandpushedtotheendofthetablistLabel  matlab.ui.control.Label
        Label3                       matlab.ui.control.Label
        Version03Label               matlab.ui.control.Label
        BugsFixesLabel               matlab.ui.control.Label
        ChangesLabel                 matlab.ui.control.Label
        HAWCIIMkALabel               matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
    end


    properties (Access = public)
        replayFlag
    end
    
    properties (Access = private)
        AdvSettingsApp % Description
    end
    
    methods (Access = public)
        
        function saveFCN(app)
            app.SVLABELButton.Text = 'Save';
            app.SVLABELButton.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.replayFlag = false;

        end

        % Value changed function: ReplayStatusButton
        function ReplayStatusButtonValueChanged(app, event)
            %value = app.ReplayStatusButton.Value;
            
            if (app.replayFlag == true)
                % Shit happens to replay then Replays
                app.ReplayStatusButton.Text = 'Replaying';
                app.ReplayStatusButton.Value = 1;
            else
                app.ReplayStatusButton.Text = 'Not Available';
                app.ReplayStatusButton.Value = 1;
            end
        end

        % Value changed function: SVLABELButton
        function SVLABELButtonValueChanged(app, event)
            % Do shit then
            app.SVLABELButton.Text = 'Saved';
            app.SVLABELButton.Value = 1;
        end

        % Value changed function: IpAddressEditField
        function IpAddressEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PortNumberEditField
        function PortNumberEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: IpAddressEditField_2
        function IpAddressEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PortNumberEditField_2
        function PortNumberEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YAxismEditField
        function YAxismEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: ZAxismEditField
        function ZAxismEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YAxismEditField_2
        function YAxismEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: ZAxismEditField_2
        function ZAxismEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PitchEditField_2
        function PitchEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: RollEditField_2
        function RollEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YawEditField_2
        function YawEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: FocalLengthmmEditField
        function FocalLengthmmEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: BaselinemmEditField
        function BaselinemmEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.CALBR_LABELLabel.Text = 'Calibrated';
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: PixelSizemmEditField
        function PixelSizemmEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: PlayGameButton
        function PlayGameButtonValueChanged(app, event)
            app.PlayGameButton.Value = 1;
            app.PlayGameButton.Text = 'Game In Progress';
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.AdvSettingsApp = Advanced_Settings_PDR(app);

            app.AdvancedButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.AdvSettingsApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveGameplayTab = uitab(app.TabGroup);
            app.LiveGameplayTab.Title = 'Live Gameplay';
            app.UIAxes4 = uiaxes(app.LiveGameplayTab);
            app.UIAxes4.XLim = [0 752];
            app.UIAxes4.YLim = [0 480];
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.TotalServeDropDownLabel = uilabel(app.LiveGameplayTab);
            app.TotalServeDropDownLabel.Text = 'Total Serve';
            app.TotalServeDropDown = uidropdown(app.LiveGameplayTab);
            app.TotalServeDropDown.Items = {'1', '2', '3', '4', '5'};
            app.TotalServeDropDown.Value = '1';
            app.ReplayStatusButton = uibutton(app.LiveGameplayTab, 'state');
            app.ReplayStatusButton.ValueChangedFcn = createCallbackFcn(app, @ReplayStatusButtonValueChanged, true);
            app.ReplayStatusButton.Text = 'ReplayStatus';
            app.ReplayStatusButton.Value = true;
            app.TotalVolleyDropDownLabel = uilabel(app.LiveGameplayTab);
            app.TotalVolleyDropDownLabel.Text = 'Total Volley';
            app.TotalVolleyDropDown = uidropdown(app.LiveGameplayTab);
            app.TotalVolleyDropDown.Items = {'1', '2', '3', '4', '5'};
            app.TotalVolleyDropDown.Value = '1';
            app.GAMELabel = uilabel(app.LiveGameplayTab);
            app.GAMELabel.Text = 'GAME:';
            app.P1_SCRLabel_2 = uilabel(app.LiveGameplayTab);
            app.P1_SCRLabel_2.Text = 'P1_SCR';
            app.Label_7 = uilabel(app.LiveGameplayTab);
            app.Label_7.Text = '--';
            app.P2_SCRLabel_2 = uilabel(app.LiveGameplayTab);
            app.P2_SCRLabel_2.Text = 'P2_SCR';
            app.LiveGameplaypoweredbyHAWCIIMkALabel = uilabel(app.LiveGameplayTab);
            app.LiveGameplaypoweredbyHAWCIIMkALabel.Text = 'Live Gameplay powered by HAWC-II Mk A';
            app.INLabel = uilabel(app.LiveGameplayTab);
            app.INLabel.Text = 'IN';
            app.INLabel_2 = uilabel(app.LiveGameplayTab);
            app.INLabel_2.Text = 'IN';
            app.PlayGameButton = uibutton(app.LiveGameplayTab, 'state');
            app.PlayGameButton.ValueChangedFcn = createCallbackFcn(app, @PlayGameButtonValueChanged, true);
            app.PlayGameButton.Text = 'Play Game';
            app.ErrorCountLabel = uilabel(app.LiveGameplayTab);
            app.ErrorCountLabel.Text = 'Error Count:';
            app.ERR_CNTLabel = uilabel(app.LiveGameplayTab);
            app.ERR_CNTLabel.Text = 'ERR_CNT';
            app.MatchDetailsTab = uitab(app.TabGroup);
            app.MatchDetailsTab.Title = 'Match Details';
            app.UIAxes5_3 = uiaxes(app.MatchDetailsTab);
            title(app.UIAxes5_3, 'Camera Error')
            xlabel(app.UIAxes5_3, 'Error [m]')
            ylabel(app.UIAxes5_3, 'Error [m]')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes5_3.XLim = [0 560];
            app.UIAxes5_3.YLim = [0 420];
            app.UIAxes5_4 = uiaxes(app.MatchDetailsTab);
            title(app.UIAxes5_4, 'Depth Results')
            xlabel(app.UIAxes5_4, 'Actual (m)')
            ylabel(app.UIAxes5_4, 'Measured (m)')
            zlabel(app.UIAxes5_4, 'Z')
            app.UIAxes5_4.XLim = [0 250];
            app.UIAxes5_4.YLim = [0 250];
            app.UIAxes6_2 = uiaxes(app.MatchDetailsTab);
            title(app.UIAxes6_2, 'Bounce')
            xlabel(app.UIAxes6_2, 'Time')
            ylabel(app.UIAxes6_2, 'Height')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6_2.XLim = [0 561];
            app.UIAxes6_2.YLim = [0 420];
            app.P1_SCRLabel = uilabel(app.MatchDetailsTab);
            app.P1_SCRLabel.Text = 'P1_SCR';
            app.Label_8 = uilabel(app.MatchDetailsTab);
            app.Label_8.Text = '--';
            app.P2_SCRLabel = uilabel(app.MatchDetailsTab);
            app.P2_SCRLabel.Text = 'P2_SCR';
            app.Label_9 = uilabel(app.MatchDetailsTab);
            app.BreakdownLabel = uilabel(app.MatchDetailsTab);
            app.BreakdownLabel.Text = 'Breakdown';
            app.Player1Label = uilabel(app.MatchDetailsTab);
            app.Player1Label.Text = 'Player #1:';
            app.InLabel = uilabel(app.MatchDetailsTab);
            app.InLabel.Text = 'In -';
            app.outLabel = uilabel(app.MatchDetailsTab);
            app.outLabel.Text = 'out -';
            app.netLabel = uilabel(app.MatchDetailsTab);
            app.netLabel.Text = 'net -';
            app.bounceLabel = uilabel(app.MatchDetailsTab);
            app.bounceLabel.Text = 'bounce -';
            app.Label_10 = uilabel(app.MatchDetailsTab);
            app.IN_SCRLabel = uilabel(app.MatchDetailsTab);
            app.IN_SCRLabel.Text = 'IN_SCR';
            app.OUT_SCRLabel = uilabel(app.MatchDetailsTab);
            app.OUT_SCRLabel.Text = 'OUT_SCR';
            app.NET_SCRLabel = uilabel(app.MatchDetailsTab);
            app.NET_SCRLabel.Text = 'NET_SCR';
            app.BNC_SCRLabel = uilabel(app.MatchDetailsTab);
            app.BNC_SCRLabel.Text = 'BNC_SCR';
            app.Player2Label = uilabel(app.MatchDetailsTab);
            app.Player2Label.Text = 'Player #2:';
            app.InLabel_2 = uilabel(app.MatchDetailsTab);
            app.InLabel_2.Text = 'In -';
            app.outLabel_2 = uilabel(app.MatchDetailsTab);
            app.outLabel_2.Text = 'out -';
            app.netLabel_2 = uilabel(app.MatchDetailsTab);
            app.netLabel_2.Text = 'net -';
            app.bounceLabel_2 = uilabel(app.MatchDetailsTab);
            app.bounceLabel_2.Text = 'bounce -';
            app.IN_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.IN_SCRLabel_2.Text = 'IN_SCR';
            app.OUT_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.OUT_SCRLabel_2.Text = 'OUT_SCR';
            app.NET_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.NET_SCRLabel_2.Text = 'NET_SCR';
            app.BNC_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.BNC_SCRLabel_2.Text = 'BNC_SCR';
            app.volleyLabel = uilabel(app.MatchDetailsTab);
            app.volleyLabel.Text = 'volley -';
            app.VLY_SCRLabel = uilabel(app.MatchDetailsTab);
            app.VLY_SCRLabel.Text = 'VLY_SCR';
            app.volleyLabel_2 = uilabel(app.MatchDetailsTab);
            app.volleyLabel_2.Text = 'volley -';
            app.VLY_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.VLY_SCRLabel_2.Text = 'VLY_SCR';
            app.serveLabel = uilabel(app.MatchDetailsTab);
            app.serveLabel.Text = 'serve -';
            app.SRV_SCRLabel = uilabel(app.MatchDetailsTab);
            app.SRV_SCRLabel.Text = 'SRV_SCR';
            app.serveLabel_2 = uilabel(app.MatchDetailsTab);
            app.serveLabel_2.Text = 'serve -';
            app.SRV_SCRLabel_2 = uilabel(app.MatchDetailsTab);
            app.SRV_SCRLabel_2.Text = 'SRV_SCR';
            app.SystemAccuracyBallPositionLabel_2 = uilabel(app.MatchDetailsTab);
            app.SystemAccuracyBallPositionLabel_2.Text = 'System Accuracy [Ball Position]';
            app.SystemAccuracyCameraPositionLabel_2 = uilabel(app.MatchDetailsTab);
            app.SystemAccuracyCameraPositionLabel_2.Text = 'System Accuracy [Camera Position]';
            app.CoefficientofRestitutionLabel_2 = uilabel(app.MatchDetailsTab);
            app.CoefficientofRestitutionLabel_2.Text = 'Coefficient of Restitution';
            app.AssumedCourtSurfaceLabel_2 = uilabel(app.MatchDetailsTab);
            app.AssumedCourtSurfaceLabel_2.Text = 'Assumed Court Surface:';
            app.SRFC_ASMLabel = uilabel(app.MatchDetailsTab);
            app.SRFC_ASMLabel.Text = 'SRFC_ASM';
            app.ActualCourtSurfaceLabel_2 = uilabel(app.MatchDetailsTab);
            app.ActualCourtSurfaceLabel_2.Text = 'Actual Court Surface:';
            app.SRFC_ACCLabel = uilabel(app.MatchDetailsTab);
            app.SRFC_ACCLabel.Text = 'SRFC_ACC';
            app.AnalyzeButton_2 = uibutton(app.MatchDetailsTab, 'push');
            app.AnalyzeButton_2.Text = 'Analyze';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ServerPropertiesLabel = uilabel(app.SettingsTab);
            app.ServerPropertiesLabel.Text = 'Server Properties';
            app.CameraPropertiesLabel = uilabel(app.SettingsTab);
            app.CameraPropertiesLabel.Text = 'Camera Properties';
            app.IpAddressEditFieldLabel = uilabel(app.SettingsTab);
            app.IpAddressEditFieldLabel.Text = 'Ip Address';
            app.IpAddressEditField = uieditfield(app.SettingsTab, 'text');
            app.IpAddressEditField.ValueChangedFcn = createCallbackFcn(app, @IpAddressEditFieldValueChanged, true);
            app.IpAddressEditField.Value = '127.0.0.1';
            app.PortNumberEditFieldLabel = uilabel(app.SettingsTab);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.SettingsTab, 'text');
            app.PortNumberEditField.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldValueChanged, true);
            app.PortNumberEditField.Value = '55001';
            app.Camera1Label = uilabel(app.SettingsTab);
            app.Camera1Label.Text = 'Camera #1';
            app.YAxismEditFieldLabel = uilabel(app.SettingsTab);
            app.YAxismEditFieldLabel.Text = 'Y Axis [m]';
            app.YAxismEditField = uieditfield(app.SettingsTab, 'numeric');
            app.YAxismEditField.ValueChangedFcn = createCallbackFcn(app, @YAxismEditFieldValueChanged, true);
            app.ZAxismEditFieldLabel = uilabel(app.SettingsTab);
            app.ZAxismEditFieldLabel.Text = 'Z Axis [m]';
            app.ZAxismEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ZAxismEditField.ValueChangedFcn = createCallbackFcn(app, @ZAxismEditFieldValueChanged, true);
            app.ZAxismEditField.Value = 35;
            app.Camera2Label = uilabel(app.SettingsTab);
            app.Camera2Label.Text = 'Camera #2';
            app.YAxismEditField_2Label = uilabel(app.SettingsTab);
            app.YAxismEditField_2Label.Text = 'Y Axis [m]';
            app.YAxismEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.YAxismEditField_2.ValueChangedFcn = createCallbackFcn(app, @YAxismEditField_2ValueChanged, true);
            app.ZAxismEditField_2Label = uilabel(app.SettingsTab);
            app.ZAxismEditField_2Label.Text = 'Z Axis [m]';
            app.ZAxismEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.ZAxismEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZAxismEditField_2ValueChanged, true);
            app.ZAxismEditField_2.Value = 35;
            app.SVLABELButton = uibutton(app.SettingsTab, 'state');
            app.SVLABELButton.ValueChangedFcn = createCallbackFcn(app, @SVLABELButtonValueChanged, true);
            app.SVLABELButton.Text = 'SV LABEL';
            app.Label_4 = uilabel(app.SettingsTab);
            app.FocalLengthmmEditFieldLabel = uilabel(app.SettingsTab);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length [mm]';
            app.FocalLengthmmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.FocalLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @FocalLengthmmEditFieldValueChanged, true);
            app.FocalLengthmmEditField.Value = 50;
            app.CalibrationStatusLabel = uilabel(app.SettingsTab);
            app.CalibrationStatusLabel.Text = 'Calibration Status:';
            app.Label_5 = uilabel(app.SettingsTab);
            app.CALBR_LABELLabel = uilabel(app.SettingsTab);
            app.CALBR_LABELLabel.Text = 'CALBR_LABEL';
            app.CalibrateButton = uibutton(app.SettingsTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.PitchEditFieldLabel = uilabel(app.SettingsTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.RollEditFieldLabel = uilabel(app.SettingsTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.SettingsTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.SettingsTab, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.PitchEditField_2Label = uilabel(app.SettingsTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.PitchEditField_2.ValueChangedFcn = createCallbackFcn(app, @PitchEditField_2ValueChanged, true);
            app.RollEditField_2Label = uilabel(app.SettingsTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.RollEditField_2.ValueChangedFcn = createCallbackFcn(app, @RollEditField_2ValueChanged, true);
            app.YawEditField_2Label = uilabel(app.SettingsTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.YawEditField_2.ValueChangedFcn = createCallbackFcn(app, @YawEditField_2ValueChanged, true);
            app.ScreenPropertiesLabel = uilabel(app.SettingsTab);
            app.ScreenPropertiesLabel.Text = 'Screen Properties';
            app.ResolutionEditFieldLabel = uilabel(app.SettingsTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 1920;
            app.xLabel = uilabel(app.SettingsTab);
            app.xLabel.Text = 'x';
            app.EditField = uieditfield(app.SettingsTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1080;
            app.PixelSizemmEditFieldLabel = uilabel(app.SettingsTab);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size [mm]';
            app.PixelSizemmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PixelSizemmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemmEditFieldValueChanged, true);
            app.PixelSizemmEditField.Value = 0.003;
            app.BaselinemmEditFieldLabel = uilabel(app.SettingsTab);
            app.BaselinemmEditFieldLabel.Text = 'Baseline [mm]';
            app.BaselinemmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.BaselinemmEditField.ValueChangedFcn = createCallbackFcn(app, @BaselinemmEditFieldValueChanged, true);
            app.BaselinemmEditField.Value = 200;
            app.BlenderPropertiesLabel = uilabel(app.SettingsTab);
            app.BlenderPropertiesLabel.Text = 'Blender Properties';
            app.IpAddressEditField_2Label = uilabel(app.SettingsTab);
            app.IpAddressEditField_2Label.Text = 'Ip Address';
            app.IpAddressEditField_2 = uieditfield(app.SettingsTab, 'text');
            app.IpAddressEditField_2.ValueChangedFcn = createCallbackFcn(app, @IpAddressEditField_2ValueChanged, true);
            app.IpAddressEditField_2.Value = '192.168.1.1';
            app.PortNumberEditField_2Label = uilabel(app.SettingsTab);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditField_2 = uieditfield(app.SettingsTab, 'text');
            app.PortNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditField_2ValueChanged, true);
            app.PortNumberEditField_2.Value = '25565';
            app.GamePropertiesLabel = uilabel(app.SettingsTab);
            app.GamePropertiesLabel.Text = 'Game Properties';
            app.CourtColorDropDown_2Label = uilabel(app.SettingsTab);
            app.CourtColorDropDown_2Label.Text = 'Court Color';
            app.CourtColorDropDown_2 = uidropdown(app.SettingsTab);
            app.CourtColorDropDown_2.Items = {'Custom', 'Green', 'Red', 'Blue', 'Gray', 'Purple'};
            app.CourtColorDropDown_2.Value = 'Custom';
            app.BallColorDropDown_2Label = uilabel(app.SettingsTab);
            app.BallColorDropDown_2Label.Text = 'Ball Color';
            app.BallColorDropDown_2 = uidropdown(app.SettingsTab);
            app.BallColorDropDown_2.Items = {'Red', 'Custom', 'Orange', 'Green', 'White'};
            app.BallColorDropDown_2.Value = 'Custom';
            app.AdvancedButton = uibutton(app.SettingsTab, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HAWC-II-Mk_A_Logo.png');
            app.Label_3 = uilabel(app.AboutTab);
            app.Label_3.Text = '    Since 2003, Hawk-Eye has been used to make calls for the United States Tennis Association [UTSA]. Recently it has been determined that under windy conditions the calls are not very accurate and creates significant errors. The HAWC-II Mk A eliminates those inaccurate results using a state-of-the-art stereo camera tracker.';
            app.Version03Label_2 = uilabel(app.AboutTab);
            app.Version03Label_2.Text = 'Version: 0.3';
            app.Label_6 = uilabel(app.AboutTab);
            app.Label_6.Text = '3/23/2025';
            app.Label_11 = uilabel(app.AboutTab);
            app.Label_11.Text = 'Utilizing a FPGA-based controller, Snickerdoodle ___, and SVPRO 1080p 5-50mm Telephoto Cameras. Challengers aim to provide UTSA with a price efficient product that doesn''t impact performance.';
            app.TeamChallengersLabel = uilabel(app.AboutTab);
            app.TeamChallengersLabel.Text = 'Team Challengers';
            app.ShamsBelalLabel = uilabel(app.AboutTab);
            app.ShamsBelalLabel.Text = 'Shams Belal';
            app.SantiagoSanchezLabel = uilabel(app.AboutTab);
            app.SantiagoSanchezLabel.Text = 'Santiago Sanchez';
            app.AvaTeegardenLabel = uilabel(app.AboutTab);
            app.AvaTeegardenLabel.Text = 'Ava Teegarden';
            app.MatthewCellsLabel = uilabel(app.AboutTab);
            app.MatthewCellsLabel.Text = 'Matthew Cells';
            app.DavidWanLabel = uilabel(app.AboutTab);
            app.DavidWanLabel.Text = 'David Wan';
            app.StephenBianchiLabel = uilabel(app.AboutTab);
            app.StephenBianchiLabel.Text = 'Stephen Bianchi';
            app.OranBetzLabel = uilabel(app.AboutTab);
            app.OranBetzLabel.Text = 'Oran Betz';
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.Image2 = uiimage(app.ChangelogTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'HAWC-II-Mk_A_Logo.png');
            app.HAWCIIMkALabel = uilabel(app.ChangelogTab);
            app.HAWCIIMkALabel.Text = 'HAWC-II Mk A';
            app.ChangesLabel = uilabel(app.ChangelogTab);
            app.ChangesLabel.Text = 'Changes';
            app.BugsFixesLabel = uilabel(app.ChangelogTab);
            app.BugsFixesLabel.Text = 'Bugs & Fixes';
            app.Version03Label = uilabel(app.ChangelogTab);
            app.Version03Label.Text = 'Version: 0.3 ';
            app.Label3 = uilabel(app.ChangelogTab);
            app.Label3.Text = '- Literally nothing works... All of it is just barebones drybones ahh GUI with no function.';
            app.HomeisnowAboutandpushedtotheendofthetablistLabel = uilabel(app.ChangelogTab);
            app.HomeisnowAboutandpushedtotheendofthetablistLabel.Text = '- "Home" is now "About" and pushed to the end of the tab list';
            app.AddedBlenderPropertiesinPropertiesTabLabel = uilabel(app.ChangelogTab);
            app.AddedBlenderPropertiesinPropertiesTabLabel.Text = '- Added Blender Properties in "Properties" Tab';
            app.AboutnowhasadescriptionofproductandteammembersLabel = uilabel(app.ChangelogTab);
            app.AboutnowhasadescriptionofproductandteammembersLabel.Text = '- "About" now has a description of product and team members';
            app.ManualPresetcolorcorrectionaddedLabel = uilabel(app.ChangelogTab);
            app.ManualPresetcolorcorrectionaddedLabel.Text = '- Manual + Preset color correction added';
            app.NewcoefficientrestitutionGUILabel = uilabel(app.ChangelogTab);
            app.NewcoefficientrestitutionGUILabel.Text = '- New coefficient restitution GUI';
            app.AddedIndicatorsforballInOutStatusnexttoLiveTVframeLabel = uilabel(app.ChangelogTab);
            app.AddedIndicatorsforballInOutStatusnexttoLiveTVframeLabel.Text = '- Added Indicators for ball In/Out Status next to Live TV frame';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PDR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end