classdef GUI_PDR_DEMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LiveGameplayTab              matlab.ui.container.Tab
        PlayGameButton               matlab.ui.control.StateButton
        INLabel_2                    matlab.ui.control.Label
        INLabel                      matlab.ui.control.Label
        LiveGameplaypoweredbyHAWCIIMkALabel  matlab.ui.control.Label
        T2_SCRLabel                  matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        T1_SCRLabel                  matlab.ui.control.Label
        GAMELabel                    matlab.ui.control.Label
        VolleyDropDown               matlab.ui.control.DropDown
        VolleyDropDownLabel          matlab.ui.control.Label
        ReplayStatusButton           matlab.ui.control.StateButton
        ServeDropDown                matlab.ui.control.DropDown
        ServeDropDownLabel           matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        MatchDetailsTab              matlab.ui.container.Tab
        WND_SPDLabel                 matlab.ui.control.Label
        WindSpeedLabel               matlab.ui.control.Label
        AnalyzeButton_2              matlab.ui.control.Button
        ACC_SRFLabel                 matlab.ui.control.Label
        ActualCourtSurfaceLabel_2    matlab.ui.control.Label
        ASS_SRFLabel                 matlab.ui.control.Label
        AssumedCourtSurfaceLabel_2   matlab.ui.control.Label
        CoefficientofRestitutionLabel_2  matlab.ui.control.Label
        SystemAccuracyBallPositionLabel  matlab.ui.control.Label
        SystemAccuracyCameraPositionLabel_3  matlab.ui.control.Label
        T2_SRV_SCRLabel              matlab.ui.control.Label
        serveLabel_2                 matlab.ui.control.Label
        T1_SRV_SCRLabel              matlab.ui.control.Label
        serveLabel                   matlab.ui.control.Label
        T2_VLL_SCRLabel              matlab.ui.control.Label
        volleyLabel_2                matlab.ui.control.Label
        T1_VLL_SCRLabel              matlab.ui.control.Label
        volleyLabel                  matlab.ui.control.Label
        T2_BNC_SCRLabel              matlab.ui.control.Label
        T2_NET_SCRLabel              matlab.ui.control.Label
        T2_OUT_SCRLabel              matlab.ui.control.Label
        T2_IN_SCRLabel               matlab.ui.control.Label
        bounceLabel_2                matlab.ui.control.Label
        netLabel_2                   matlab.ui.control.Label
        outLabel_2                   matlab.ui.control.Label
        InLabel_2                    matlab.ui.control.Label
        Team2Label                   matlab.ui.control.Label
        T1_BNC_SCRLabel              matlab.ui.control.Label
        T1_NET_SCRLabel              matlab.ui.control.Label
        T1_OUT_SCRLabel              matlab.ui.control.Label
        T1_IN_SCRLabel               matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        bounceLabel                  matlab.ui.control.Label
        netLabel                     matlab.ui.control.Label
        outLabel                     matlab.ui.control.Label
        InLabel                      matlab.ui.control.Label
        Team1Label                   matlab.ui.control.Label
        BreakdownLabel               matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        T2_SCR_MLabel                matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        T1_SCR_MLabel                matlab.ui.control.Label
        UIAxes6_2                    matlab.ui.control.UIAxes
        UIAxes5_4                    matlab.ui.control.UIAxes
        UIAxes5_3                    matlab.ui.control.UIAxes
        SettingsTab                  matlab.ui.container.Tab
        DiagnosticsButton            matlab.ui.control.Button
        BallColorDropDown_2          matlab.ui.control.DropDown
        BallColorDropDown_2Label     matlab.ui.control.Label
        CourtColorDropDown_2         matlab.ui.control.DropDown
        CourtColorDropDown_2Label    matlab.ui.control.Label
        GamePropertiesLabel          matlab.ui.control.Label
        PortNumberEditField_2        matlab.ui.control.EditField
        PortNumberEditField_2Label   matlab.ui.control.Label
        IpAddressEditField_2         matlab.ui.control.EditField
        IpAddressEditField_2Label    matlab.ui.control.Label
        BlenderPropertiesLabel       matlab.ui.control.Label
        BaselinemmEditField          matlab.ui.control.NumericEditField
        BaselinemmEditFieldLabel     matlab.ui.control.Label
        PixelSizemmEditField         matlab.ui.control.NumericEditField
        PixelSizemmEditFieldLabel    matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        xLabel                       matlab.ui.control.Label
        ResolutionEditField          matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel     matlab.ui.control.Label
        ScreenPropertiesLabel        matlab.ui.control.Label
        YawEditField_2               matlab.ui.control.NumericEditField
        YawEditField_2Label          matlab.ui.control.Label
        RollEditField_2              matlab.ui.control.NumericEditField
        RollEditField_2Label         matlab.ui.control.Label
        PitchEditField_2             matlab.ui.control.NumericEditField
        PitchEditField_2Label        matlab.ui.control.Label
        YawEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel            matlab.ui.control.Label
        RollEditField                matlab.ui.control.NumericEditField
        RollEditFieldLabel           matlab.ui.control.Label
        PitchEditField               matlab.ui.control.NumericEditField
        PitchEditFieldLabel          matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        CALBR_LABELLabel             matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        CalibrationStatusLabel       matlab.ui.control.Label
        FocalLengthmmEditField       matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel  matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        SVLABELButton                matlab.ui.control.StateButton
        ZAxismEditField_2            matlab.ui.control.NumericEditField
        ZAxismEditField_2Label       matlab.ui.control.Label
        YAxismEditField_2            matlab.ui.control.NumericEditField
        YAxismEditField_2Label       matlab.ui.control.Label
        Camera2Label                 matlab.ui.control.Label
        ZAxismEditField              matlab.ui.control.NumericEditField
        ZAxismEditFieldLabel         matlab.ui.control.Label
        YAxismEditField              matlab.ui.control.NumericEditField
        YAxismEditFieldLabel         matlab.ui.control.Label
        Camera1Label                 matlab.ui.control.Label
        PortNumberEditField          matlab.ui.control.EditField
        PortNumberEditFieldLabel     matlab.ui.control.Label
        IpAddressEditField           matlab.ui.control.EditField
        IpAddressEditFieldLabel      matlab.ui.control.Label
        CameraPropertiesLabel        matlab.ui.control.Label
        ServerPropertiesLabel        matlab.ui.control.Label
        AboutTab                     matlab.ui.container.Tab
        OranBetzLabel                matlab.ui.control.Label
        StephenBianchiLabel          matlab.ui.control.Label
        DavidWanLabel                matlab.ui.control.Label
        MatthewCellsLabel            matlab.ui.control.Label
        AvaTeegardenLabel            matlab.ui.control.Label
        SantiagoSanchezLabel         matlab.ui.control.Label
        ShamsBelalLabel              matlab.ui.control.Label
        TeamChallengersLabel         matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Version04Label               matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ChangelogTab                 matlab.ui.container.Tab
        WorkingLiveTVwithServesGameplayLabel_2  matlab.ui.control.Label
        WorkingLiveTVwithServesGameplayLabel  matlab.ui.control.Label
        Version04Label_2             matlab.ui.control.Label
        ChangesLabel                 matlab.ui.control.Label
        HAWCIIMkALabel               matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
    end

    properties (Access = public)
% ---- [ Iterations ] --------------
        serveIteration
        volleyIteration
        gameIteration
% ----------------------------------
% ------ [ Flags ] -----------------
        replayFlag
        saveFlag
        gameInProgressFlag
        settingsEnteredFlag
        diagnosticsOpenedFlag
% ----------------------------------
%------ [ Dat Files ] --------------
        imageArrLeft
        imageArrRight
        imageArrLive
% ----------------------------------
% -- [ Blender Properties ] --------
        blenderIpAddress
        blenderPortNumber
        client
% ----------------------------------
% --- [ Sensor Properties ] --------
        resolutionWidth
        resolutionHeight
        pixelSize
% ----------------------------------
% -- [ Cam #1 Settings ] -----------
        camOneY
        camOneZ
        camOnePitch
        camOneRoll
        camOneYaw
% ----------------------------------
% -- [ Cam #2 Settings ] -----------
        camTwoY
        camTwoZ
        camTwoPitch
        camTwoRoll
        camTwoYaw
% ----------------------------------
% -- [ Cam CRWD Settings ] ---------
        camCrwdX
        camCrwdY
        camCrwdZ
        camCrwdPitch
        camCrwdRoll
        camCrwdYaw
% ----------------------------------
% --- [ Cam Properties ] -----------
        focalLength
        baseline
% ----------------------------------
    end
    
    properties (Access = private)
        AdvSettingsApp % Description
    end
    
    methods (Access = public)
        
        function saveFCN(app)
            app.SVLABELButton.Text = 'Save';
            app.SVLABELButton.Value = 0;
            app.saveFlag = 1;
        end
        
        function ball_detection(app, frame)
            % Left camera
            leftImage = (app.imageArrLeft(:,:,:, frame));
            leftNormalImage = mat2gray(leftImage);
            leftGray = rgb2gray(leftNormalImage);

            leftKernel = [1 1 1;
                          1 1 1;
                          1 1 1;];

            avgImgLeft = conv2(leftGray, leftKernel);

            leftEdgeDetect = edge(avgImgLeft);
            [leftCenters, leftRadii] = my_imfindcircles(leftEdgeDetect, [6 app.resolutionWidth], 'Sensitivity', 0.845);

            % Right Camera
            rightImage = (app.imageArrRight(:,:,:, frame));
            rightNormalImage = mat2gray(rightImage);
            rightGray = rgb2gray(rightNormalImage);

            rightKernel = [1 1 1;
                          1 1 1;
                          1 1 1;];

            avgRightLeft = conv2(rightGray, rightKernel);
            rightEdgeDetect = edge(avgRightLeft);

            [rightCenters, rightRadii] = my_imfindcircles(rightEdgeDetect, [6 app.resolutionWidth], 'Sensitivity', 0.845);

            if app.diagnosticsOpenedFlag == 1
                imagesc(app.AdvSettingsApp.UIAxes_2, leftEdgeDetect);
                viscircles(app.AdvSettingsApp.UIAxes_2, leftCenters, leftRadii, 'EdgeColor', 'b');
                colormap(app.AdvSettingsApp.UIAxes_2, "Gray")

                imagesc(app.AdvSettingsApp.UIAxes2_2, rightEdgeDetect);
                viscircles(app.AdvSettingsApp.UIAxes2_2, rightCenters, rightRadii, 'EdgeColor', 'b');
                colormap(app.AdvSettingsApp.UIAxes2_2, "Gray")
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.replayFlag = false;
            app.diagnosticsOpenedFlag = 0;
            
            if isfile('sysSettings\system_init.mat') == 0
                initializeStatus = 0;
                save('sysSettings\system_init.mat', 'initializeStatus')
            end

            app.INLabel.BackgroundColor = 'none'; app.INLabel.Text = '';
            app.INLabel_2.BackgroundColor = 'none'; app.INLabel_2.Text = '';
            
% -------------- [ Settings ] --------------------
            app.settingsEnteredFlag = 0;

            app.blenderIpAddress = '127.0.0.1';
            app.blenderPortNumber = '55001';

            app.camOneY = 0; app.camOneZ = 32;
            app.camOnePitch = 0; app.camOneRoll = 0; app.camOneYaw = 0;

            app.camTwoY = 0; app.camTwoZ = 32;
            app.camTwoPitch = 0; app.camTwoRoll = 0; app.camTwoYaw = 0;

            app.camCrwdX = -15.084; app.camCrwdY = 24.032; app.camCrwdZ = 16.532;
            app.camCrwdPitch = 58.801; app.camCrwdRoll = 1.0106; app.camCrwdYaw = -149.18;

            app.baseline = 1000;
            app.focalLength = 8;

            app.resolutionWidth = 752;
            app.resolutionHeight = 480;
            app.pixelSize = 0.003;
% ----------------------------------------------
        end

        % Value changed function: ReplayStatusButton
        function ReplayStatusButtonValueChanged(app, event)
            %value = app.ReplayStatusButton.Value;
        end

        % Value changed function: SVLABELButton
        function SVLABELButtonValueChanged(app, event)
            % Do shit then
            if (app.saveFlag == 1)
% -----------------------------------------------------
% ---------------- [ Snick Props ] --------------------
            % Add Snickerdoodle Board properties for Settings Tab
% -----------------------------------------------------
% --------------- [ Blender Props ] -------------------                
                app.blenderIpAddress = app.IpAddressEditField_2.Value;
                app.blenderPortNumber = app.PortNumberEditField_2.Value;
% -----------------------------------------------------
% --------------- [ Cam One Props ] -------------------
                app.camOneY     =   app.YAxismEditField.Value;
                app.camOneZ     =   app.ZAxismEditField.Value;
                app.camOnePitch =   app.PitchEditField.Value;
                app.camOneRoll  =   app.RollEditField.Value;
                app.camOneYaw   =   app.YawEditField.Value;
% -----------------------------------------------------
% --------------- [ Cam Two Props ] -------------------
                app.camTwoY = app.YAxismEditField_2.Value;
                app.camTwoZ = app.ZAxismEditField_2.Value;
                app.camTwoPitch = app.PitchEditField_2.Value;
                app.camTwoRoll = app.RollEditField_2.Value;
                app.camTwoYaw = app.YawEditField_2.Value;
% -----------------------------------------------------
% --------------- [ Cam Settings ] --------------------
                app.focalLength = app.FocalLengthmmEditField.Value;
                app.baseline = app.BaselinemmEditField.Value;
% -----------------------------------------------------
% --------------- [ Screen Props ] --------------------
                app.resolutionWidth = app.ResolutionEditField.Value;
                app.resolutionHeight = app.EditField.Value;
                app.pixelSize = app.PixelSizemmEditField.Value;
% -----------------------------------------------------
                initializeStatus = 0;
                save('sysSettings\system_init.mat', 'initializeStatus');
            else
                app.SVLABELButton.Text = 'Saved';
                app.SVLABELButton.Value = 1;
            end
        end

        % Value changed function: IpAddressEditField
        function IpAddressEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PortNumberEditField
        function PortNumberEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PortNumberEditField_2
        function PortNumberEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YAxismEditField
        function YAxismEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: ZAxismEditField
        function ZAxismEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YAxismEditField_2
        function YAxismEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: ZAxismEditField_2
        function ZAxismEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: PitchEditField_2
        function PitchEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: RollEditField_2
        function RollEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: YawEditField_2
        function YawEditField_2ValueChanged(app, event)
            saveFCN(app)
        end

        % Value changed function: FocalLengthmmEditField
        function FocalLengthmmEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: BaselinemmEditField
        function BaselinemmEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % Awaiting Stephen's Camera Calibration
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: PixelSizemmEditField
        function PixelSizemmEditFieldValueChanged(app, event)
            saveFCN(app)
            app.CALBR_LABELLabel.Text = 'Not Calibrated';
        end

        % Value changed function: PlayGameButton
        function PlayGameButtonValueChanged(app, event)
            frameTimeMS = 30;
            app.gameIteration = 1;
            load('sysSettings\system_init.mat', 'initializeStatus');
            if (initializeStatus == 0)
                app.PlayGameButton.Enable = 'off';
                app.PlayGameButton.Text = 'Initializing';
                app.client = tcpclient(app.blenderIpAddress, str2double(app.blenderPortNumber), "Timeout", 20);

                for serveCounter = 1:5
                    currentServe = readmatrix("datFiles\serve" + serveCounter + ".dat");
                    
                    dataSize = size(currentServe);
                    numFrames = dataSize(1);

                    imageLive = zeros(app.resolutionHeight, app.resolutionWidth, 3, numFrames, "uint8");
                    imageLeft = zeros(app.resolutionHeight, app.resolutionWidth, 3, numFrames, "uint8");
                    imageRight = zeros(app.resolutionHeight, app.resolutionWidth, 3, numFrames, "uint8");

                    for imageCounter = 1:numFrames
                        x = currentServe(imageCounter, 1);
                        y = currentServe(imageCounter, 3);
                        z = currentServe(imageCounter, 2);

                        % Crowd Cam
                        blenderLink(app.client,app.resolutionWidth, app.resolutionHeight, (y), -(x), z, 0, 0, 0, "tennisBall");
                        % Crowd Cam
                        imageLive(:,:,:,imageCounter) = blenderLink(app.client,app.resolutionWidth, app.resolutionHeight, ...
                                        app.camCrwdX, app.camCrwdY, app.camCrwdZ, app.camCrwdPitch, app.camCrwdRoll, app.camCrwdYaw, "Camera");
                        % Cam One
                        imageLeft(:,:,:,imageCounter) = blenderLink(app.client,app.resolutionWidth, app.resolutionHeight, ...
                                        ((app.baseline/1000)/2), app.camOneY, app.camOneZ, app.camOnePitch, app.camOneRoll, app.camOneYaw, "Camera");
                        % Cam Two
                        imageRight(:,:,:,imageCounter) = blenderLink(app.client,app.resolutionWidth, app.resolutionHeight, ...
                                        -((app.baseline/1000)/2), app.camTwoY, app.camTwoZ, app.camTwoPitch, app.camTwoRoll, app.camTwoYaw, "Camera");

                        disp("Frame #" + imageCounter+ " Complete of Set #" + serveCounter)
                    end % Frame Reciever [For Loop]
                    disp("Set #" + serveCounter+ " Complete");
                    file_name_live = sprintf('imgData\\LiveTV_Set_%d.mat', serveCounter);    save(file_name_live, 'imageLive', '-v7.3');
                    file_name_right = sprintf('imgData\\rightCam_Set_%d.mat', serveCounter); save(file_name_right, 'imageRight', '-v7.3');
                    file_name_left = sprintf('imgData\\leftCam_Set_%d.mat', serveCounter);   save(file_name_left, 'imageLeft', '-v7.3');
                    disp("Set #" + serveCounter + " Saved");

                end % serveCounter [For Loop]
                initializeStatus = 1;
                save('sysSettings\system_init.mat', 'initializeStatus')

            end % Initialize [If Statement]
            app.PlayGameButton.Enable = 'off';
            app.PlayGameButton.Text = 'Loading';

            app.serveIteration = str2double(app.ServeDropDown.Value);
            load_liveImg_name = sprintf('imgData\\LiveTV_Set_%d.mat', app.serveIteration);
            load(load_liveImg_name, 'imageLive'); app.imageArrLive = imageLive;

            % Testing
            load_leftImg_name = sprintf('imgData\\leftCam_Set_%d.mat', app.serveIteration);
            load(load_leftImg_name, 'imageLeft'); app.imageArrLeft = imageLeft;

            load_rightImg_name = sprintf('imgData\\rightCam_Set_%d.mat', app.serveIteration);
            load(load_rightImg_name, 'imageRight'); app.imageArrRight = imageRight;
            
            currentServe = readmatrix("datFiles\serve" + app.serveIteration + ".dat");

            dataSize = size(currentServe);
            numFrames = dataSize(1);

            disp(numFrames)

            app.PlayGameButton.Enable = 'off';
            app.PlayGameButton.Text = 'Game In Progress';
            
            for gameCounter = 1:frameTimeMS:numFrames
                app.gameIteration = gameCounter;
                % Live TV
                pause(0.0000000000005);
                imagesc(app.UIAxes4, (app.imageArrLive(:,:,:, gameCounter)));
                app.UIAxes4.XLim = ([0,app.resolutionWidth]);
                app.UIAxes4.YLim = ([0, app.resolutionHeight]);
                
                if app.diagnosticsOpenedFlag == 1
                    imagesc(app.AdvSettingsApp.UIAxes, (app.imageArrLeft(:,:,:, gameCounter)));
                    app.AdvSettingsApp.UIAxes.XLim = ([0,app.resolutionWidth]);
                    app.AdvSettingsApp.UIAxes.YLim = ([0, app.resolutionHeight]);

                    imagesc(app.AdvSettingsApp.UIAxes2, (app.imageArrRight(:,:,:, gameCounter)));
                    app.AdvSettingsApp.UIAxes2.XLim = ([0,app.resolutionWidth]);
                    app.AdvSettingsApp.UIAxes2.YLim = ([0, app.resolutionHeight]);
                end

                % Find Ball
                ball_detection(app, gameCounter)
                % Detect Bounce/Replay Setup
            end
            app.PlayGameButton.Enable = 'on';
            app.PlayGameButton.Value = 0;
            app.PlayGameButton.Text = 'Play Game';
        end

        % Button pushed function: DiagnosticsButton
        function DiagnosticsButtonPushed(app, event)
            app.AdvSettingsApp = Advanced_Settings_PDR(app);
            app.diagnosticsOpenedFlag = 1;
            app.DiagnosticsButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.AdvSettingsApp)
            delete(app)
        end

        % Button down function: SettingsTab
        function SettingsTabButtonDown(app, event)
            % Dsiplay
            if (app.settingsEnteredFlag == 0)
            disp("Settings Tab Entered");
% ---------------- [ Entry House ] --------------------
            app.SVLABELButton.Value = 1;
            app.SVLABELButton.Text = 'Saved';
            app.saveFlag = 0;
% -----------------------------------------------------
% ---------------- [ Snick Props ] --------------------
            % Add Snickerdoodle Board properties for Settings Tab
% -----------------------------------------------------
% --------------- [ Blender Props ] -------------------
            app.IpAddressEditField_2.Value = app.blenderIpAddress;
            app.PortNumberEditField_2.Value = app.blenderPortNumber;
% -----------------------------------------------------
% --------------- [ Cam One Props ] -------------------
            app.YAxismEditField.Value = app.camOneY;
            app.ZAxismEditField.Value = app.camOneZ;
            app.PitchEditField.Value  = app.camOnePitch;
            app.RollEditField.Value   = app.camOneRoll;
            app.YawEditField.Value    = app.camOneYaw;
% -----------------------------------------------------
% --------------- [ Cam Two Props ] -------------------
            app.YAxismEditField_2.Value = app.camTwoY;
            app.ZAxismEditField_2.Value = app.camTwoZ;
            app.PitchEditField_2.Value  = app.camTwoPitch;
            app.RollEditField_2.Value   = app.camTwoRoll;
            app.YawEditField_2.Value    = app.camTwoYaw;
% -----------------------------------------------------
% --------------- [ Cam Settings ] --------------------
            app.BaselinemmEditField.Value = app.baseline;
            app.FocalLengthmmEditField.Value = app.focalLength;
% -----------------------------------------------------
% --------------- [ Screen Props ] --------------------
            app.PixelSizemmEditField.Value = app.pixelSize;
            app.ResolutionEditField.Value = app.resolutionWidth;
            app.EditField.Value = app.resolutionHeight;
% -----------------------------------------------------
            app.settingsEnteredFlag = 1; % Exit Condition
            end
        end

        % Button down function: LiveGameplayTab
        function LiveGameplayTabButtonDown(app, event)
            app.settingsEnteredFlag = 0; % To ensure that we don't have settings tab override on each click.
        end

        % Button down function: MatchDetailsTab
        function MatchDetailsTabButtonDown(app, event)
            app.settingsEnteredFlag = 0; % To ensure that we don't have settings tab override on each click.
        end

        % Button down function: AboutTab
        function AboutTabButtonDown(app, event)
            app.settingsEnteredFlag = 0; % To ensure that we don't have settings tab override on each click.
        end

        % Button down function: ChangelogTab
        function ChangelogTabButtonDown(app, event)
            app.settingsEnteredFlag = 0; % To ensure that we don't have settings tab override on each click.
        end

        % Value changed function: IpAddressEditField_2
        function IpAddressEditField_2ValueChanged(app, event)
            saveFCN(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveGameplayTab = uitab(app.TabGroup);
            app.LiveGameplayTab.Title = 'Live Gameplay';
            app.LiveGameplayTab.ButtonDownFcn = createCallbackFcn(app, @LiveGameplayTabButtonDown, true);
            app.UIAxes4 = uiaxes(app.LiveGameplayTab);
            app.UIAxes4.XLim = [0 1920];
            app.UIAxes4.YLim = [0 1080];
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.ServeDropDownLabel = uilabel(app.LiveGameplayTab);
            app.ServeDropDownLabel.Text = 'Serve';
            app.ServeDropDown = uidropdown(app.LiveGameplayTab);
            app.ServeDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ServeDropDown.Value = '1';
            app.ReplayStatusButton = uibutton(app.LiveGameplayTab, 'state');
            app.ReplayStatusButton.ValueChangedFcn = createCallbackFcn(app, @ReplayStatusButtonValueChanged, true);
            app.ReplayStatusButton.Text = 'Replay Status';
            app.ReplayStatusButton.Value = true;
            app.VolleyDropDownLabel = uilabel(app.LiveGameplayTab);
            app.VolleyDropDownLabel.Enable = 'off';
            app.VolleyDropDownLabel.Text = 'Volley';
            app.VolleyDropDown = uidropdown(app.LiveGameplayTab);
            app.VolleyDropDown.Items = {'1', '2', '3', '4', '5'};
            app.VolleyDropDown.Enable = 'off';
            app.VolleyDropDown.Value = '1';
            app.GAMELabel = uilabel(app.LiveGameplayTab);
            app.GAMELabel.Text = 'GAME:';
            app.T1_SCRLabel = uilabel(app.LiveGameplayTab);
            app.T1_SCRLabel.Text = 'T1_SCR';
            app.Label_7 = uilabel(app.LiveGameplayTab);
            app.Label_7.Text = '--';
            app.T2_SCRLabel = uilabel(app.LiveGameplayTab);
            app.T2_SCRLabel.Text = 'T2_SCR';
            app.LiveGameplaypoweredbyHAWCIIMkALabel = uilabel(app.LiveGameplayTab);
            app.LiveGameplaypoweredbyHAWCIIMkALabel.Text = 'Live Gameplay powered by HAWC-II Mk A';
            app.INLabel = uilabel(app.LiveGameplayTab);
            app.INLabel.Text = 'IN';
            app.INLabel_2 = uilabel(app.LiveGameplayTab);
            app.INLabel_2.Text = 'IN';
            app.PlayGameButton = uibutton(app.LiveGameplayTab, 'state');
            app.PlayGameButton.ValueChangedFcn = createCallbackFcn(app, @PlayGameButtonValueChanged, true);
            app.PlayGameButton.Text = 'Play Game';
            app.MatchDetailsTab = uitab(app.TabGroup);
            app.MatchDetailsTab.Title = 'Match Details';
            app.MatchDetailsTab.ButtonDownFcn = createCallbackFcn(app, @MatchDetailsTabButtonDown, true);
            app.UIAxes5_3 = uiaxes(app.MatchDetailsTab);
            title(app.UIAxes5_3, 'Camera Error')
            xlabel(app.UIAxes5_3, 'Error [m]')
            ylabel(app.UIAxes5_3, 'Error [m]')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes5_3.XLim = [0 560];
            app.UIAxes5_3.YLim = [0 420];
            app.UIAxes5_4 = uiaxes(app.MatchDetailsTab);
            title(app.UIAxes5_4, 'Depth Results')
            xlabel(app.UIAxes5_4, 'Actual (m)')
            ylabel(app.UIAxes5_4, 'Measured (m)')
            zlabel(app.UIAxes5_4, 'Z')
            app.UIAxes5_4.XLim = [0 250];
            app.UIAxes5_4.YLim = [0 250];
            app.UIAxes6_2 = uiaxes(app.MatchDetailsTab);
            title(app.UIAxes6_2, 'Bounce')
            xlabel(app.UIAxes6_2, 'Time')
            ylabel(app.UIAxes6_2, 'Height')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6_2.XLim = [0 561];
            app.UIAxes6_2.YLim = [0 420];
            app.T1_SCR_MLabel = uilabel(app.MatchDetailsTab);
            app.T1_SCR_MLabel.Text = 'T1_SCR_M';
            app.Label_8 = uilabel(app.MatchDetailsTab);
            app.Label_8.Text = '--';
            app.T2_SCR_MLabel = uilabel(app.MatchDetailsTab);
            app.T2_SCR_MLabel.Text = 'T2_SCR_M';
            app.Label_9 = uilabel(app.MatchDetailsTab);
            app.BreakdownLabel = uilabel(app.MatchDetailsTab);
            app.BreakdownLabel.Text = 'Breakdown';
            app.Team1Label = uilabel(app.MatchDetailsTab);
            app.Team1Label.Text = 'Team #1:';
            app.InLabel = uilabel(app.MatchDetailsTab);
            app.InLabel.Text = 'In -';
            app.outLabel = uilabel(app.MatchDetailsTab);
            app.outLabel.Text = 'out -';
            app.netLabel = uilabel(app.MatchDetailsTab);
            app.netLabel.Text = 'net -';
            app.bounceLabel = uilabel(app.MatchDetailsTab);
            app.bounceLabel.Text = 'bounce -';
            app.Label_10 = uilabel(app.MatchDetailsTab);
            app.T1_IN_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T1_IN_SCRLabel.Text = 'T1_IN_SCR';
            app.T1_OUT_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T1_OUT_SCRLabel.Text = 'T1_OUT_SCR';
            app.T1_NET_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T1_NET_SCRLabel.Text = 'T1_NET_SCR';
            app.T1_BNC_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T1_BNC_SCRLabel.Text = 'T1_BNC_SCR';
            app.Team2Label = uilabel(app.MatchDetailsTab);
            app.Team2Label.Text = 'Team #2:';
            app.InLabel_2 = uilabel(app.MatchDetailsTab);
            app.InLabel_2.Text = 'In -';
            app.outLabel_2 = uilabel(app.MatchDetailsTab);
            app.outLabel_2.Text = 'out -';
            app.netLabel_2 = uilabel(app.MatchDetailsTab);
            app.netLabel_2.Text = 'net -';
            app.bounceLabel_2 = uilabel(app.MatchDetailsTab);
            app.bounceLabel_2.Text = 'bounce -';
            app.T2_IN_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T2_IN_SCRLabel.Text = 'T2_IN_SCR';
            app.T2_OUT_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T2_OUT_SCRLabel.Text = 'T2_OUT_SCR';
            app.T2_NET_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T2_NET_SCRLabel.Text = 'T2_NET_SCR';
            app.T2_BNC_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T2_BNC_SCRLabel.Text = 'T2_BNC_SCR';
            app.volleyLabel = uilabel(app.MatchDetailsTab);
            app.volleyLabel.Text = 'volley -';
            app.T1_VLL_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T1_VLL_SCRLabel.Text = 'T1_VLL_SCR';
            app.volleyLabel_2 = uilabel(app.MatchDetailsTab);
            app.volleyLabel_2.Text = 'volley -';
            app.T2_VLL_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T2_VLL_SCRLabel.Text = 'T2_VLL_SCR';
            app.serveLabel = uilabel(app.MatchDetailsTab);
            app.serveLabel.Text = 'serve -';
            app.T1_SRV_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T1_SRV_SCRLabel.Text = 'T1_SRV_SCR';
            app.serveLabel_2 = uilabel(app.MatchDetailsTab);
            app.serveLabel_2.Text = 'serve -';
            app.T2_SRV_SCRLabel = uilabel(app.MatchDetailsTab);
            app.T2_SRV_SCRLabel.Text = 'T2_SRV_SCR';
            app.SystemAccuracyCameraPositionLabel_3 = uilabel(app.MatchDetailsTab);
            app.SystemAccuracyCameraPositionLabel_3.Text = 'System Accuracy [Camera Position]';
            app.SystemAccuracyBallPositionLabel = uilabel(app.MatchDetailsTab);
            app.SystemAccuracyBallPositionLabel.Text = 'System Accuracy [Ball Position]';
            app.CoefficientofRestitutionLabel_2 = uilabel(app.MatchDetailsTab);
            app.CoefficientofRestitutionLabel_2.Text = 'Coefficient of Restitution';
            app.AssumedCourtSurfaceLabel_2 = uilabel(app.MatchDetailsTab);
            app.AssumedCourtSurfaceLabel_2.Text = 'Assumed Court Surface:';
            app.ASS_SRFLabel = uilabel(app.MatchDetailsTab);
            app.ASS_SRFLabel.Text = 'ASS_SRF';
            app.ActualCourtSurfaceLabel_2 = uilabel(app.MatchDetailsTab);
            app.ActualCourtSurfaceLabel_2.Text = 'Actual Court Surface:';
            app.ACC_SRFLabel = uilabel(app.MatchDetailsTab);
            app.ACC_SRFLabel.Text = 'ACC_SRF';
            app.AnalyzeButton_2 = uibutton(app.MatchDetailsTab, 'push');
            app.AnalyzeButton_2.Text = 'Analyze';
            app.WindSpeedLabel = uilabel(app.MatchDetailsTab);
            app.WindSpeedLabel.Text = 'Wind Speed: ';
            app.WND_SPDLabel = uilabel(app.MatchDetailsTab);
            app.WND_SPDLabel.Text = 'WND_SPD';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsTab.ButtonDownFcn = createCallbackFcn(app, @SettingsTabButtonDown, true);
            app.ServerPropertiesLabel = uilabel(app.SettingsTab);
            app.ServerPropertiesLabel.Text = 'Server Properties';
            app.CameraPropertiesLabel = uilabel(app.SettingsTab);
            app.CameraPropertiesLabel.Text = 'Camera Properties';
            app.IpAddressEditFieldLabel = uilabel(app.SettingsTab);
            app.IpAddressEditFieldLabel.Text = 'Ip Address';
            app.IpAddressEditField = uieditfield(app.SettingsTab, 'text');
            app.IpAddressEditField.ValueChangedFcn = createCallbackFcn(app, @IpAddressEditFieldValueChanged, true);
            app.PortNumberEditFieldLabel = uilabel(app.SettingsTab);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.SettingsTab, 'text');
            app.PortNumberEditField.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldValueChanged, true);
            app.Camera1Label = uilabel(app.SettingsTab);
            app.Camera1Label.Text = 'Camera #1';
            app.YAxismEditFieldLabel = uilabel(app.SettingsTab);
            app.YAxismEditFieldLabel.Text = 'Y Axis [m]';
            app.YAxismEditField = uieditfield(app.SettingsTab, 'numeric');
            app.YAxismEditField.ValueChangedFcn = createCallbackFcn(app, @YAxismEditFieldValueChanged, true);
            app.ZAxismEditFieldLabel = uilabel(app.SettingsTab);
            app.ZAxismEditFieldLabel.Text = 'Z Axis [m]';
            app.ZAxismEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ZAxismEditField.ValueChangedFcn = createCallbackFcn(app, @ZAxismEditFieldValueChanged, true);
            app.Camera2Label = uilabel(app.SettingsTab);
            app.Camera2Label.Text = 'Camera #2';
            app.YAxismEditField_2Label = uilabel(app.SettingsTab);
            app.YAxismEditField_2Label.Text = 'Y Axis [m]';
            app.YAxismEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.YAxismEditField_2.ValueChangedFcn = createCallbackFcn(app, @YAxismEditField_2ValueChanged, true);
            app.ZAxismEditField_2Label = uilabel(app.SettingsTab);
            app.ZAxismEditField_2Label.Text = 'Z Axis [m]';
            app.ZAxismEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.ZAxismEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZAxismEditField_2ValueChanged, true);
            app.SVLABELButton = uibutton(app.SettingsTab, 'state');
            app.SVLABELButton.ValueChangedFcn = createCallbackFcn(app, @SVLABELButtonValueChanged, true);
            app.SVLABELButton.Text = 'SV LABEL';
            app.Label_4 = uilabel(app.SettingsTab);
            app.FocalLengthmmEditFieldLabel = uilabel(app.SettingsTab);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length [mm]';
            app.FocalLengthmmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.FocalLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @FocalLengthmmEditFieldValueChanged, true);
            app.CalibrationStatusLabel = uilabel(app.SettingsTab);
            app.CalibrationStatusLabel.Text = 'Calibration Status:';
            app.Label_5 = uilabel(app.SettingsTab);
            app.CALBR_LABELLabel = uilabel(app.SettingsTab);
            app.CALBR_LABELLabel.Text = 'CALBR_LABEL';
            app.CalibrateButton = uibutton(app.SettingsTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.PitchEditFieldLabel = uilabel(app.SettingsTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.RollEditFieldLabel = uilabel(app.SettingsTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.SettingsTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.SettingsTab, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.PitchEditField_2Label = uilabel(app.SettingsTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.PitchEditField_2.ValueChangedFcn = createCallbackFcn(app, @PitchEditField_2ValueChanged, true);
            app.RollEditField_2Label = uilabel(app.SettingsTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.RollEditField_2.ValueChangedFcn = createCallbackFcn(app, @RollEditField_2ValueChanged, true);
            app.YawEditField_2Label = uilabel(app.SettingsTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.YawEditField_2.ValueChangedFcn = createCallbackFcn(app, @YawEditField_2ValueChanged, true);
            app.ScreenPropertiesLabel = uilabel(app.SettingsTab);
            app.ScreenPropertiesLabel.Text = 'Screen Properties';
            app.ResolutionEditFieldLabel = uilabel(app.SettingsTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.xLabel = uilabel(app.SettingsTab);
            app.xLabel.Text = 'x';
            app.EditField = uieditfield(app.SettingsTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PixelSizemmEditFieldLabel = uilabel(app.SettingsTab);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size [mm]';
            app.PixelSizemmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PixelSizemmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemmEditFieldValueChanged, true);
            app.BaselinemmEditFieldLabel = uilabel(app.SettingsTab);
            app.BaselinemmEditFieldLabel.Text = 'Baseline [mm]';
            app.BaselinemmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.BaselinemmEditField.ValueChangedFcn = createCallbackFcn(app, @BaselinemmEditFieldValueChanged, true);
            app.BlenderPropertiesLabel = uilabel(app.SettingsTab);
            app.BlenderPropertiesLabel.Text = 'Blender Properties';
            app.IpAddressEditField_2Label = uilabel(app.SettingsTab);
            app.IpAddressEditField_2Label.Text = 'Ip Address';
            app.IpAddressEditField_2 = uieditfield(app.SettingsTab, 'text');
            app.IpAddressEditField_2.ValueChangedFcn = createCallbackFcn(app, @IpAddressEditField_2ValueChanged, true);
            app.PortNumberEditField_2Label = uilabel(app.SettingsTab);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditField_2 = uieditfield(app.SettingsTab, 'text');
            app.PortNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditField_2ValueChanged, true);
            app.GamePropertiesLabel = uilabel(app.SettingsTab);
            app.GamePropertiesLabel.Text = 'Game Properties';
            app.CourtColorDropDown_2Label = uilabel(app.SettingsTab);
            app.CourtColorDropDown_2Label.Text = 'Court Color';
            app.CourtColorDropDown_2 = uidropdown(app.SettingsTab);
            app.CourtColorDropDown_2.Items = {'Custom', 'Green', 'Red', 'Blue', 'Gray', 'Purple'};
            app.CourtColorDropDown_2.Value = 'Custom';
            app.BallColorDropDown_2Label = uilabel(app.SettingsTab);
            app.BallColorDropDown_2Label.Text = 'Ball Color';
            app.BallColorDropDown_2 = uidropdown(app.SettingsTab);
            app.BallColorDropDown_2.Items = {'Red', 'Custom', 'Orange', 'Green', 'White'};
            app.BallColorDropDown_2.Value = 'Custom';
            app.DiagnosticsButton = uibutton(app.SettingsTab, 'push');
            app.DiagnosticsButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticsButtonPushed, true);
            app.DiagnosticsButton.Text = 'Diagnostics';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTab.ButtonDownFcn = createCallbackFcn(app, @AboutTabButtonDown, true);
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HAWC-II-Mk_A_Logo.png');
            app.Label_3 = uilabel(app.AboutTab);
            app.Label_3.Text = '    Since 2003, Hawk-Eye has been used to make calls for the United States Tennis Association [UTSA]. Recently it has been determined that under windy conditions the calls are not very accurate and creates significant errors. The HAWC-II Mk A eliminates those inaccurate results using a state-of-the-art stereo camera tracker.';
            app.Version04Label = uilabel(app.AboutTab);
            app.Version04Label.Text = 'Version: 0.4 ';
            app.Label_6 = uilabel(app.AboutTab);
            app.Label_6.Text = '4/7/2025';
            app.Label_11 = uilabel(app.AboutTab);
            app.Label_11.Text = 'Utilizing a FPGA-based controller, Snickerdoodle One, and SVPRO 1080p 5-50mm Telephoto Cameras. Challengers aim to provide UTSA with a price efficient product that doesn''t impact performance.';
            app.TeamChallengersLabel = uilabel(app.AboutTab);
            app.TeamChallengersLabel.Text = 'Team Challengers';
            app.ShamsBelalLabel = uilabel(app.AboutTab);
            app.ShamsBelalLabel.Text = 'Shams Belal';
            app.SantiagoSanchezLabel = uilabel(app.AboutTab);
            app.SantiagoSanchezLabel.Text = 'Santiago Sanchez';
            app.AvaTeegardenLabel = uilabel(app.AboutTab);
            app.AvaTeegardenLabel.Text = 'Ava Teegarden';
            app.MatthewCellsLabel = uilabel(app.AboutTab);
            app.MatthewCellsLabel.Text = 'Matthew Cells';
            app.DavidWanLabel = uilabel(app.AboutTab);
            app.DavidWanLabel.Text = 'David Wan';
            app.StephenBianchiLabel = uilabel(app.AboutTab);
            app.StephenBianchiLabel.Text = 'Stephen Bianchi';
            app.OranBetzLabel = uilabel(app.AboutTab);
            app.OranBetzLabel.Text = 'Oran Betz';
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.ChangelogTab.ButtonDownFcn = createCallbackFcn(app, @ChangelogTabButtonDown, true);
            app.Image2 = uiimage(app.ChangelogTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'HAWC-II-Mk_A_Logo.png');
            app.HAWCIIMkALabel = uilabel(app.ChangelogTab);
            app.HAWCIIMkALabel.Text = 'HAWC-II Mk A';
            app.ChangesLabel = uilabel(app.ChangelogTab);
            app.ChangesLabel.Text = 'Changes';
            app.Version04Label_2 = uilabel(app.ChangelogTab);
            app.Version04Label_2.Text = 'Version: 0.4';
            app.WorkingLiveTVwithServesGameplayLabel = uilabel(app.ChangelogTab);
            app.WorkingLiveTVwithServesGameplayLabel.Text = '- Working Live TV with Serves Gameplay';
            app.WorkingLiveTVwithServesGameplayLabel_2 = uilabel(app.ChangelogTab);
            app.WorkingLiveTVwithServesGameplayLabel_2.Text = '- Able to change Blender Properties + Camera Properties (Won''t Apply till new game + Saved)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PDR_DEMO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end